classdef GUI_v2_Tabbed_layout_20190404 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        STOPALLButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        QuickAdjustTab                  matlab.ui.container.Tab
        IronManPanel                    matlab.ui.container.Panel
        SetSpeedcmsLabel                matlab.ui.control.Label
        QATab_IronMan_SetSpeedSlider    matlab.ui.control.Slider
        SetFollowingDistancecmLabel     matlab.ui.control.Label
        QATab_IronMan_SetFollowingDistanceSlider  matlab.ui.control.Slider
        ConnectedLampLabel              matlab.ui.control.Label
        QATab_IronMan_ConnectedLamp     matlab.ui.control.Lamp
        QATab_IronMan_ConnectButton     matlab.ui.control.StateButton
        QATab_IronMan_SetSpeedNumeric   matlab.ui.control.NumericEditField
        QATab_IronMan_SetFollowingDistanceNumeric  matlab.ui.control.NumericEditField
        UPDATEALLPanel                  matlab.ui.container.Panel
        SETALLSPEEDScmsSliderLabel      matlab.ui.control.Label
        SETALLSPEEDScmsSlider           matlab.ui.control.Slider
        SETALLFOLLOWINGDISTcmSliderLabel  matlab.ui.control.Label
        SETALLFOLLOWINGDISTcmSlider     matlab.ui.control.Slider
        CONNECTALLButton                matlab.ui.control.StateButton
        UpdateAll_SetSpeedNumeric       matlab.ui.control.NumericEditField
        UpdateAll_SetFollowingDistanceNumeric  matlab.ui.control.NumericEditField
        ThorPanel                       matlab.ui.container.Panel
        SetSpeedcmsLabel_2              matlab.ui.control.Label
        QATab_Thor_SetSpeedSlider       matlab.ui.control.Slider
        SetFollowingDistancecmLabel_2   matlab.ui.control.Label
        QATab_Thor_SetFollowingDistanceSlider  matlab.ui.control.Slider
        ConnectedLamp_2Label            matlab.ui.control.Label
        QATab_Thor_ConnectedLamp        matlab.ui.control.Lamp
        QATab_Thor_ConnectButton        matlab.ui.control.StateButton
        QATab_Thor_SetSpeedNumeric      matlab.ui.control.NumericEditField
        QATab_Thor_SetFollowingDistanceNumeric  matlab.ui.control.NumericEditField
        HulkPanel                       matlab.ui.container.Panel
        SetSpeedcmsLabel_3              matlab.ui.control.Label
        QATab_Hulk_SetSpeedSlider       matlab.ui.control.Slider
        SetFollowingDistancecmLabel_3   matlab.ui.control.Label
        QATab_Hulk_SetFollowingDistanceSlider  matlab.ui.control.Slider
        ConnectedLamp_3Label            matlab.ui.control.Label
        QATab_Hulk_ConnectedLamp        matlab.ui.control.Lamp
        QATab_Hulk_ConnectButton        matlab.ui.control.StateButton
        QATab_Hulk_SetSpeedNumeric      matlab.ui.control.NumericEditField
        QATab_Hulk_SetFollowingDistanceNumeric  matlab.ui.control.NumericEditField
        CaptainAmericaPanel             matlab.ui.container.Panel
        SetSpeedcmsLabel_4              matlab.ui.control.Label
        QATab_CaptainAmerica_SetSpeedSlider  matlab.ui.control.Slider
        SetFollowingDistancecmLabel_4   matlab.ui.control.Label
        QATab_CaptainAmerica_SetFollowingDistanceSlider  matlab.ui.control.Slider
        ConnectedLamp_4Label            matlab.ui.control.Label
        QATab_CaptainAmerica_ConnectedLamp  matlab.ui.control.Lamp
        QATab_CaptainAmerica_ConnectButton  matlab.ui.control.StateButton
        QATab_CaptainAmerica_SetSpeedNumeric  matlab.ui.control.NumericEditField
        QATab_CaptainAmerica_SetFollowingDistanceNumeric  matlab.ui.control.NumericEditField
        IronManTab                      matlab.ui.container.Tab
        IronManTab_LeftLabel            matlab.ui.control.Label
        IronManTab_MiddleLabel          matlab.ui.control.Label
        IronManTab_RightLabel           matlab.ui.control.Label
        IronManTab_MiddleLamp           matlab.ui.control.Lamp
        IronManTab_LeftSensorVal        matlab.ui.control.Label
        IronManTab_RightSensorVal       matlab.ui.control.Label
        SetSpeedSlider_5Label           matlab.ui.control.Label
        IronManTab_SetSpeedSlider       matlab.ui.control.Slider
        SetFollowingDistanceSlider_5Label  matlab.ui.control.Label
        IronManTab_SetFollowingDistanceSlider  matlab.ui.control.Slider
        ConnectedLampLabel_2            matlab.ui.control.Label
        IronManTab_ConnectedLamp        matlab.ui.control.Lamp
        SpeedometerGauge_5Label         matlab.ui.control.Label
        IronManTab_SpeedometerGauge     matlab.ui.control.SemicircularGauge
        UltrasonicSensorGauge_5Label    matlab.ui.control.Label
        IronManTab_UltrasonicSensorGauge  matlab.ui.control.LinearGauge
        DataloggingLampLabel            matlab.ui.control.Label
        IronManTab_DataloggingLamp      matlab.ui.control.Lamp
        IronManTab_EvaluationPanel      matlab.ui.container.Panel
        IronManTab_C1Label              matlab.ui.control.Label
        IronManTab_C1Val                matlab.ui.control.Label
        IronManTab_C2Label              matlab.ui.control.Label
        IronManTab_C2Val                matlab.ui.control.Label
        IronManTab_C3Label              matlab.ui.control.Label
        IronManTab_C3Val                matlab.ui.control.Label
        IronManTab_BatteryVoltageLabel  matlab.ui.control.Label
        IronManTab_BatteryVoltageValue  matlab.ui.control.Label
        IronManTab_CommandPanel         matlab.ui.container.Panel
        IronManTab_CommandbeingexecutedLabel  matlab.ui.control.Label
        IronManTab_CommandBeingExecutedText  matlab.ui.control.Label
        IronMaTab_TabGroup              matlab.ui.container.TabGroup
        IronManTab_HighlevelTab         matlab.ui.container.Tab
        DriveLabel                      matlab.ui.control.Label
        IronManTab_DriveTask            matlab.ui.control.ListBox
        IronManTab_Task                 matlab.ui.container.ButtonGroup
        IronManTab_DriveButton          matlab.ui.control.RadioButton
        IronManTab_ParkButton           matlab.ui.control.RadioButton
        IronManTab_UpdateHighCommandButton  matlab.ui.control.Button
        IronManTab_LowlevelTab          matlab.ui.container.Tab
        StopdistanceEditFieldLabel      matlab.ui.control.Label
        IronManTab_StopDistanceField    matlab.ui.control.NumericEditField
        StopdegreesEditFieldLabel       matlab.ui.control.Label
        IronManTab_StopDegreesField     matlab.ui.control.NumericEditField
        IronManTab_Navigation           matlab.ui.container.ButtonGroup
        IronManTab_LinefollowingButton  matlab.ui.control.RadioButton
        IronManTab_DeadreckoningButton  matlab.ui.control.RadioButton
        IronManTab_StopCondition        matlab.ui.container.ButtonGroup
        IronManTab_NoneButton           matlab.ui.control.RadioButton
        IronManTab_DistanceButton       matlab.ui.control.RadioButton
        IronManTab_DegreesButton        matlab.ui.control.RadioButton
        IronManTab_BluetapeButton       matlab.ui.control.RadioButton
        IronManTab_RedtapeButton        matlab.ui.control.RadioButton
        IronManTab_ErrorSignal          matlab.ui.container.ButtonGroup
        IronManTab_LeftsensorButton     matlab.ui.control.RadioButton
        IronManTab_BothsensorsButton    matlab.ui.control.RadioButton
        IronManTab_RightsensorButton    matlab.ui.control.RadioButton
        IronManTab_UpdateLowCommandButton  matlab.ui.control.Button
        IronManTab_ConnectButton        matlab.ui.control.StateButton
        IronManTab_DataLogButton        matlab.ui.control.StateButton
        ThorTab                         matlab.ui.container.Tab
        ThorTab_LeftLabel               matlab.ui.control.Label
        ThorTab_MiddleLabel             matlab.ui.control.Label
        ThorTab_RightLabel              matlab.ui.control.Label
        ThorTab_MiddleLamp              matlab.ui.control.Lamp
        ThorTab_LeftSensorVal           matlab.ui.control.Label
        ThorTab_RightSensorVal          matlab.ui.control.Label
        ThorTab_EvaluationPanel         matlab.ui.container.Panel
        ThorTab_C1Label                 matlab.ui.control.Label
        ThorTab_C1Val                   matlab.ui.control.Label
        ThorTab_C2Label                 matlab.ui.control.Label
        ThorTab_C2Val                   matlab.ui.control.Label
        ThorTab_C3Label                 matlab.ui.control.Label
        ThorTab_C3Val                   matlab.ui.control.Label
        ThorTab_BatteryVoltageLabel     matlab.ui.control.Label
        ThorTab_BatteryVoltageValue     matlab.ui.control.Label
        SetSpeedSlider_7Label           matlab.ui.control.Label
        ThorTab_SetSpeedSlider          matlab.ui.control.Slider
        SetFollowingDistanceSlider_7Label  matlab.ui.control.Label
        ThorTab_SetFollowingDistanceSlider  matlab.ui.control.Slider
        ConnectedLamp_2Label_2          matlab.ui.control.Label
        ThorTab_ConnectedLamp           matlab.ui.control.Lamp
        SpeedometerGauge_8Label         matlab.ui.control.Label
        ThorTab_SpeedometerGauge        matlab.ui.control.SemicircularGauge
        UltrasonicSensorGauge_8Label    matlab.ui.control.Label
        ThorTab_UltrasonicSensorGauge   matlab.ui.control.LinearGauge
        DataloggingLamp_2Label          matlab.ui.control.Label
        ThorTab_DataloggingLamp         matlab.ui.control.Lamp
        ThorTab_ConnectButton           matlab.ui.control.StateButton
        ThorTab_DataLogButton           matlab.ui.control.StateButton
        ThorTab_CommandPanel            matlab.ui.container.Panel
        ThorTab_CommandbeingexecutedLabel  matlab.ui.control.Label
        ThorTab_CommandBeingExecutedText  matlab.ui.control.Label
        ThorTab_TabGroup                matlab.ui.container.TabGroup
        ThorTab_HighlevelTab            matlab.ui.container.Tab
        DriveLabel_2                    matlab.ui.control.Label
        ThorTab_DriveTask               matlab.ui.control.ListBox
        ThorTab_UpdateHighCommandButton  matlab.ui.control.Button
        ThorTab_Task                    matlab.ui.container.ButtonGroup
        ThorTab_DriveButton             matlab.ui.control.RadioButton
        ThorTab_ParkButton              matlab.ui.control.RadioButton
        ThorTab_LowlevelTab             matlab.ui.container.Tab
        StopdistanceEditFieldLabel_2    matlab.ui.control.Label
        ThorTab_StopDistanceField       matlab.ui.control.NumericEditField
        StopdegreesEditFieldLabel_2     matlab.ui.control.Label
        ThorTab_StopDegreesField        matlab.ui.control.NumericEditField
        ThorTab_Navigation              matlab.ui.container.ButtonGroup
        ThorTab_LinefollowingButton     matlab.ui.control.RadioButton
        ThorTab_DeadreckoningButton     matlab.ui.control.RadioButton
        ThorTab_StopCondition           matlab.ui.container.ButtonGroup
        ThorTab_NoneButton              matlab.ui.control.RadioButton
        ThorTab_DistanceButton          matlab.ui.control.RadioButton
        ThorTab_DegreesButton           matlab.ui.control.RadioButton
        ThorTab_BluetapeButton          matlab.ui.control.RadioButton
        ThorTab_RedtapeButton           matlab.ui.control.RadioButton
        ThorTab_ErrorSignal             matlab.ui.container.ButtonGroup
        ThorTab_LeftsensorButton        matlab.ui.control.RadioButton
        ThorTab_BothsensorsButton       matlab.ui.control.RadioButton
        ThorTab_RightsensorButton       matlab.ui.control.RadioButton
        ThorTab_UpdateLowCommandButton  matlab.ui.control.Button
        HulkTab                         matlab.ui.container.Tab
        HulkTab_LeftLabel               matlab.ui.control.Label
        HulkTab_MiddleLabel             matlab.ui.control.Label
        HulkTab_RightLabel              matlab.ui.control.Label
        HulkTab_MiddleLamp              matlab.ui.control.Lamp
        HulkTab_LeftSensorVal           matlab.ui.control.Label
        HulkTab_RightSensorVal          matlab.ui.control.Label
        HulkTab_EvaluationPanel         matlab.ui.container.Panel
        HulkTab_C1Label                 matlab.ui.control.Label
        HulkTab_C1Val                   matlab.ui.control.Label
        HulkTab_C2Label                 matlab.ui.control.Label
        HulkTab_C2Val                   matlab.ui.control.Label
        HulkTab_C3Label                 matlab.ui.control.Label
        HulkTab_C3Val                   matlab.ui.control.Label
        HulkTab_BatteryVoltageLabel     matlab.ui.control.Label
        HulkTab_BatteryVoltageValue     matlab.ui.control.Label
        SetSpeedSlider_9Label           matlab.ui.control.Label
        HulkTab_SetSpeedSlider          matlab.ui.control.Slider
        SetFollowingDistanceSlider_9Label  matlab.ui.control.Label
        HulkTab_SetFollowingDistanceSlider  matlab.ui.control.Slider
        ConnectedLamp_4Label_2          matlab.ui.control.Label
        HulkTab_ConnectedLamp           matlab.ui.control.Lamp
        SpeedometerGauge_9Label         matlab.ui.control.Label
        HulkTab_SpeedometerGauge        matlab.ui.control.SemicircularGauge
        UltrasonicSensorGauge_9Label    matlab.ui.control.Label
        HulkTab_UltrasonicSensorGauge   matlab.ui.control.LinearGauge
        DataloggingLamp_3Label          matlab.ui.control.Label
        HulkTab_DataloggingLamp         matlab.ui.control.Lamp
        HulkTab_ConnectButton           matlab.ui.control.StateButton
        HulkTab_DataLogButton           matlab.ui.control.StateButton
        HulkTab_CommandPanel            matlab.ui.container.Panel
        HulkTab_CommandbeingexecutedLabel  matlab.ui.control.Label
        HulkTab_CommandBeingExecutedText  matlab.ui.control.Label
        HulkTab_TabGroup                matlab.ui.container.TabGroup
        HulkTab_HighlevelTab            matlab.ui.container.Tab
        DriveLabel_3                    matlab.ui.control.Label
        HulkTab_DriveTask               matlab.ui.control.ListBox
        HulkTab_UpdateHighCommandButton  matlab.ui.control.Button
        HulkTab_Task                    matlab.ui.container.ButtonGroup
        HulkTab_DriveButton             matlab.ui.control.RadioButton
        HulkTab_ParkButton              matlab.ui.control.RadioButton
        HulkTab_LowlevelTab             matlab.ui.container.Tab
        StopdistanceEditFieldLabel_3    matlab.ui.control.Label
        HulkTab_StopDistanceField       matlab.ui.control.NumericEditField
        StopdegreesEditFieldLabel_3     matlab.ui.control.Label
        HulkTab_StopDegreesField        matlab.ui.control.NumericEditField
        HulkTab_Navigation              matlab.ui.container.ButtonGroup
        HulkTab_LinefollowingButton     matlab.ui.control.RadioButton
        HulkTab_DeadreckoningButton     matlab.ui.control.RadioButton
        HulkTab_StopCondition           matlab.ui.container.ButtonGroup
        HulkTab_NoneButton              matlab.ui.control.RadioButton
        HulkTab_DistanceButton          matlab.ui.control.RadioButton
        HulkTab_DegreesButton           matlab.ui.control.RadioButton
        HulkTab_BluetapeButton          matlab.ui.control.RadioButton
        HulkTab_RedtapeButton           matlab.ui.control.RadioButton
        HulkTab_ErrorSignal             matlab.ui.container.ButtonGroup
        HulkTab_LeftsensorButton        matlab.ui.control.RadioButton
        HulkTab_BothsensorsButton       matlab.ui.control.RadioButton
        HulkTab_RightsensorButton       matlab.ui.control.RadioButton
        HulkTab_UpdateLowCommandButton  matlab.ui.control.Button
        CaptainAmericaTab               matlab.ui.container.Tab
        CaptainAmericaTab_LeftLabel     matlab.ui.control.Label
        CaptainAmericaTab_MiddleLabel   matlab.ui.control.Label
        CaptainAmericaTab_RightLabel    matlab.ui.control.Label
        CaptainAmericaTab_MiddleLamp    matlab.ui.control.Lamp
        CaptainAmericaTab_LeftSensorVal  matlab.ui.control.Label
        CaptainAmericaTab_RightSensorVal  matlab.ui.control.Label
        CaptainAmericaTab_EvaluationPanel  matlab.ui.container.Panel
        CaptainAmericaTab_C1Label       matlab.ui.control.Label
        CaptainAmericaTab_C1Val         matlab.ui.control.Label
        CaptainAmericaTab_C2Label       matlab.ui.control.Label
        CaptainAmericaTab_C2Val         matlab.ui.control.Label
        CaptainAmericaTab_C3Label       matlab.ui.control.Label
        CaptainAmericaTab_C3Val         matlab.ui.control.Label
        CaptainAmericaTab_BatteryVoltageLabel  matlab.ui.control.Label
        CaptainAmericaTab_BatteryVoltageValue  matlab.ui.control.Label
        SetSpeedSlider_11Label          matlab.ui.control.Label
        CaptainAmericaTab_SetSpeedSlider  matlab.ui.control.Slider
        SetFollowingDistanceSlider_11Label  matlab.ui.control.Label
        CaptainAmericaTab_SetFollowingDistanceSlider  matlab.ui.control.Slider
        ConnectedLamp_5Label            matlab.ui.control.Label
        CaptainAmericaTab_ConnectedLamp  matlab.ui.control.Lamp
        SpeedometerGauge_10Label        matlab.ui.control.Label
        CaptainAmericaTab_SpeedometerGauge  matlab.ui.control.SemicircularGauge
        UltrasonicSensorGauge_10Label   matlab.ui.control.Label
        CaptainAmericaTab_UltrasonicSensorGauge  matlab.ui.control.LinearGauge
        DataloggingLamp_4Label          matlab.ui.control.Label
        CaptainAmericaTab_DataloggingLamp  matlab.ui.control.Lamp
        CaptainAmericaTab_ConnectButton  matlab.ui.control.StateButton
        CaptainAmericaTab_DataLogButton  matlab.ui.control.StateButton
        CaptainAmericaTab_CommandPanel  matlab.ui.container.Panel
        CaptainAmericaTab_CommandbeingexecutedLabel  matlab.ui.control.Label
        CaptainAmericaTab_CommandBeingExecutedText  matlab.ui.control.Label
        CaptainAmericaTab_TabGroup      matlab.ui.container.TabGroup
        CaptainAmericaTab_HighlevelTab  matlab.ui.container.Tab
        DriveLabel_4                    matlab.ui.control.Label
        CaptainAmericaTab_DriveTask     matlab.ui.control.ListBox
        CaptainAmericaTab_UpdateHighCommandButton  matlab.ui.control.Button
        CaptainAmericaTab_Task          matlab.ui.container.ButtonGroup
        CaptainAmericaTab_DriveButton   matlab.ui.control.RadioButton
        CaptainAmericaTab_ParkButton    matlab.ui.control.RadioButton
        CaptainAmericaTab_LowlevelTab   matlab.ui.container.Tab
        StopdistanceEditFieldLabel_4    matlab.ui.control.Label
        CaptainAmericaTab_StopDistanceField  matlab.ui.control.NumericEditField
        StopdegreesEditFieldLabel_4     matlab.ui.control.Label
        CaptainAmericaTab_StopDegreesField  matlab.ui.control.NumericEditField
        CaptainAmericaTab_Navigation    matlab.ui.container.ButtonGroup
        CaptainAmericaTab_LinefollowingButton  matlab.ui.control.RadioButton
        CaptainAmericaTab_DeadreckoningButton  matlab.ui.control.RadioButton
        CaptainAmericaTab_StopCondition  matlab.ui.container.ButtonGroup
        CaptainAmericaTab_NoneButton    matlab.ui.control.RadioButton
        CaptainAmericaTab_DistanceButton  matlab.ui.control.RadioButton
        CaptainAmericaTab_DegreesButton  matlab.ui.control.RadioButton
        CaptainAmericaTab_BluetapeButton  matlab.ui.control.RadioButton
        CaptainAmericaTab_RedtapeButton  matlab.ui.control.RadioButton
        CaptainAmericaTab_ErrorSignal   matlab.ui.container.ButtonGroup
        CaptainAmericaTab_LeftsensorButton  matlab.ui.control.RadioButton
        CaptainAmericaTab_BothsensorsButton  matlab.ui.control.RadioButton
        CaptainAmericaTab_RightsensorButton  matlab.ui.control.RadioButton
        CaptainAmericaTab_UpdateLowCommandButton  matlab.ui.control.Button
        StatusMessagesTextAreaLabel     matlab.ui.control.Label
        StatusMessagesTextArea          matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        iSpace_Map
        Arrow_length = 40;
        IronManData
        IronManLine
        IronManClock
        IronManFileName
        IronManFileID
        ThorData
        ThorLine
        ThorClock
        ThorFileName
        ThorFileID
        HulkData
        HulkLine
        HulkClock
        HulkFileName
        HulkFileID
        CaptainAmericaData
        CaptainAmericaLine
        CaptainAmericaClock
        CaptainAmericaFileName
        CaptainAmericaFileID
        TestLine
        ObserverList
        NextPoint
    end
    
    methods (Access = private)        
        
        function InitializeMap(app)
            %disp('Inside InitializeMap.');
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            app.iSpace_Map = imshow('iSpaceMapV2.0_BIG.png','Parent',app.UIAxes,'XData',[1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
            app.iSpace_Map.ButtonDownFcn = {@app.Map_Click};
            app.UIAxes.XLim = [0 app.iSpace_Map.XData(2)];
            app.UIAxes.YLim = [0 app.iSpace_Map.YData(2)];
        end
        
        function InitializeVariables(app)
            %disp('Inside InitializeVariables.');
            app.IronManData = DataFromGUI();
            app.ThorData = DataFromGUI();
            app.HulkData = DataFromGUI();
            app.CaptainAmericaData = DataFromGUI();
            tic;
        end
    
        function InitializeLines(app)
            %disp('Inside InitializeLines.');
            hold(app.UIAxes, 'on');
            app.IronManLine = line(app.UIAxes,[0 0 0],[0 0 0],'Color','red','LineWidth',1.5,'Marker','s','MarkerFaceColor','red','MarkerEdgeColor','black','MarkerSize',5,'DisplayName','Iron Man');
            app.ThorLine = line(app.UIAxes,[0 0 0],[0 0 0],'Color','yellow','LineWidth',1.5,'Marker','d','MarkerFaceColor','yellow','MarkerEdgeColor','black','MarkerSize',5,'DisplayName','Thor');
            app.HulkLine = line(app.UIAxes,[0 0 0],[0 0 0],'Color','green','LineWidth',1.5,'Marker','h','MarkerFaceColor','green','MarkerEdgeColor','black','MarkerSize',5,'DisplayName','Hulk');
            app.CaptainAmericaLine = line(app.UIAxes,[0 0 0],[0 0 0],'Color','blue','LineWidth',1.5,'Marker','o','MarkerFaceColor','blue','MarkerEdgeColor','black','MarkerSize',5,'DisplayName','Captain America');
            %app.TestLine = line(app.UIAxes,[0],[0],'Color','cyan','LineWidth',1.5,'Marker','h','MarkerFaceColor','cyan','MarkerSize',5,'DisplayName','Test');
            legend(app.UIAxes,'Location', 'northwest');
            hold(app.UIAxes, 'off');
        end
    
        function Draw(app, name, x, z, yaw)
            %disp('x')
            %disp(x)
            %disp('z')
            %disp(z)
            %disp('yaw')
            %disp(yaw)
            y2 = app.ConvertXtoPixels(x);
            x2 = app.ConvertZtoPixels(z);
            x1 = x2-cos(yaw*pi/180)*app.Arrow_length/2;
            x3 = x2+cos(yaw*pi/180)*app.Arrow_length/2;
            y1 = y2+sin(yaw*pi/180)*app.Arrow_length/2;
            y3 = y2-sin(yaw*pi/180)*app.Arrow_length/2;
            switch(name)
                case 'Iron Man'
                    set(app.IronManLine, 'XData', [x1 x2 x3], 'YData', [y1 y2 y3]);
                case 'Thor'
                    set(app.ThorLine, 'XData', [x1 x2 x3], 'YData', [y1 y2 y3]);
                case 'Hulk'
                    set(app.HulkLine, 'XData', [x1 x2 x3], 'YData', [y1 y2 y3]);
                case 'Captain America'
                    set(app.CaptainAmericaLine, 'XData', [x1 x2 x3], 'YData', [y1 y2 y3]);
            end
        end
    
        function y_in_pixels = ConvertXtoPixels(~, x_OptiTrack)
            y_in_pixels = 159 + (x_OptiTrack*132);
        end
        
        function x_in_pixels = ConvertZtoPixels(~, z_OptiTrack)
            x_in_pixels = 159 - (z_OptiTrack*132);
        end
    
        function Notify(app)
            %disp('Inside Notify.');
            if (~isempty(app.ObserverList))
                for x = 1:length(app.ObserverList)
                    switch(app.ObserverList(x).Name)
                        case 'Iron Man'
                            app.ObserverList(x).UpdateDataFromGUI(app.IronManData);
                        case 'Thor'
                            app.ObserverList(x).UpdateDataFromGUI(app.ThorData);
                        case 'Hulk'
                            app.ObserverList(x).UpdateDataFromGUI(app.HulkData);
                        case 'Captain America'
                            app.ObserverList(x).UpdateDataFromGUI(app.CaptainAmericaData);
                    end
                end
            end
        end
    
        function UpdateAllData(app, name, val)
            switch(name)
                case 'Speed'
                    app.SETALLSPEEDScmsSlider.Value = val;
                    app.UpdateAll_SetSpeedNumeric.Value = val;
                case 'FollowDistance'
                    app.SETALLFOLLOWINGDISTcmSlider.Value = val;
                    app.UpdateAll_SetFollowingDistanceNumeric.Value = val;
            end
            app.UpdateIronManData(name, val);
            app.UpdateThorData(name, val);
            app.UpdateHulkData(name, val);
            app.UpdateCaptainAmericaData(name, val);
        end
    
        function UpdateIronManData(app, name, val)
            %disp('Inside UpdateIronManData.')
            switch(name)
                case 'Connected'
                    app.IronManData.Connected = val;
                    app.QATab_IronMan_ConnectButton.Value = val;
                    app.IronManTab_ConnectButton.Value = val;
                    if (val)
                        app.QATab_IronMan_ConnectedLamp.Color = 'green';
                        app.IronManTab_ConnectedLamp.Color = 'green';
                    else
                        app.QATab_IronMan_ConnectedLamp.Color = 'red';
                        app.IronManTab_ConnectedLamp.Color = 'red';
                    end
                case 'Speed'
                    app.IronManData.Speed = val;
                    app.QATab_IronMan_SetSpeedSlider.Value = val;
                    app.QATab_IronMan_SetSpeedNumeric.Value = val;
                    app.IronManTab_SetSpeedSlider.Value = val;
                case 'FollowDistance'
                    app.IronManData.FollowDistance = val;
                    app.QATab_IronMan_SetFollowingDistanceSlider.Value = val;
                    app.QATab_IronMan_SetFollowingDistanceNumeric.Value = val;
                    app.IronManTab_SetFollowingDistanceSlider.Value = val;
                case 'LineFollow'
                    app.IronManData.LineFollow = val;
                    app.IronManTab_LinefollowingButton.Value = val;
                    %disp('app.IronManTab_LinefollowingButton.Value');
                    %disp(app.IronManTab_LinefollowingButton.Value);
                case 'Stop_Distance'
                    app.IronManData.Stop_Distance = val;
                    app.IronManTab_StopDistanceField.Value = val;
                case 'Stop_Degrees'
                    app.IronManData.Stop_Degrees = val;
                    app.IronManTab_StopDegreesField.Value = val;
                case 'StopCond'
                    switch(val)
                        case 'None'
                            app.IronManData.myStopCond = StopCond.None;
                        case 'Distance'
                            app.IronManData.myStopCond = StopCond.Distance;
                        case 'Degrees'
                            app.IronManData.myStopCond = StopCond.Degrees;
                        case 'Blue tape'
                            app.IronManData.myStopCond = StopCond.Blue;
                        case 'Red tape'
                            app.IronManData.myStopCond = StopCond.Red;
                    end
                case 'ErrorSignal'
                    switch(val)
                        case 'Left sensor'
                            app.IronManData.myErrorSignal = ErrorSignal.Left;
                        case 'Both sensors'
                            app.IronManData.myErrorSignal = ErrorSignal.Straight;
                        case 'Right sensor'
                            app.IronManData.myErrorSignal = ErrorSignal.Right;
                    end
                case 'Task'
                    switch(val)
                        case 'LowLevel'
                            app.IronManData.myTask = Task.LowLevel;
                            app.IronManTab_CommandBeingExecutedText.Text = 'Low level';
                        case 'Outer loop - whole'
                            app.IronManData.myTask = Task.DriveOuterLoopWhole;
                            app.IronManTab_CommandBeingExecutedText.Text = 'Drive the outer loop (whole)';
                        case 'Outer loop - left'
                            app.IronManData.myTask = Task.DriveOuterLoopLeft;
                            app.IronManTab_CommandBeingExecutedText.Text = 'Drive the outer loop (left)';
                        case 'Outer loop - right'
                            app.IronManData.myTask = Task.DriveOuterLoopRight;
                            app.IronManTab_CommandBeingExecutedText.Text = 'Drive the outer loop (right)';
                        case 'Inner loop - whole'
                            app.IronManData.myTask = Task.DriveInnerLoopWhole;
                            app.IronManTab_CommandBeingExecutedText.Text = 'Drive the inner loop (whole)';
                        case 'Inner loop - left'
                            app.IronManData.myTask = Task.DriveInnerLoopLeft;
                            app.IronManTab_CommandBeingExecutedText.Text = 'Drive the inner loop (left)';
                        case 'Inner loop - right'
                            app.IronManData.myTask = Task.DriveInnerLoopRight;
                            app.IronManTab_CommandBeingExecutedText.Text = 'Drive the inner loop (right)';
                        case 'Random'
                            app.IronManData.myTask = Task.DriveRandom;
                            app.IronManTab_CommandBeingExecutedText.Text = 'Drive randomly';
                        case 'Park'
                            app.IronManData.myTask = Task.Park;
                            app.IronManTab_CommandBeingExecutedText.Text = 'Park';
                    end
                case 'PacketNum'
                    app.IronManData.IncrementPacketNum = 1;
            end
            app.Notify();
            app.IronManData.IncrementPacketNum = 0;
        end
                    
        function UpdateThorData(app, name, val)
            %disp('Inside UpdateThorData.')
            switch(name)
                case 'Connected'
                    app.ThorData.Connected = val;
                    app.QATab_Thor_ConnectButton.Value = val;
                    app.ThorTab_ConnectButton.Value = val;
                    if (val)
                        app.QATab_Thor_ConnectedLamp.Color = 'green';
                        app.ThorTab_ConnectedLamp.Color = 'green';
                    else
                        app.QATab_Thor_ConnectedLamp.Color = 'red';
                        app.ThorTab_ConnectedLamp.Color = 'red';
                    end
                case 'Speed'
                    app.ThorData.Speed = val;
                    app.QATab_Thor_SetSpeedSlider.Value = val;
                    app.QATab_Thor_SetSpeedNumeric.Value = val;
                    app.ThorTab_SetSpeedSlider.Value = val;
                case 'FollowDistance'
                    app.ThorData.FollowDistance = val;
                    app.QATab_Thor_SetFollowingDistanceSlider.Value = val;
                    app.QATab_Thor_SetFollowingDistanceNumeric.Value = val;
                    app.ThorTab_SetFollowingDistanceSlider.Value = val;
                case 'LineFollow'
                    app.ThorData.LineFollow = val;
                    app.ThorTab_LinefollowingButton.Value = val;
                    %disp('app.ThorTab_LinefollowingButton.Value');
                    %disp(app.ThorTab_LinefollowingButton.Value);
                case 'Stop_Distance'
                    app.ThorData.Stop_Distance = val;
                    app.ThorTab_StopDistanceField.Value = val;
                case 'Stop_Degrees'
                    app.ThorData.Stop_Degrees = val;
                    app.ThorTab_StopDegreesField.Value = val;
                case 'StopCond'
                    switch(val)
                        case 'None'
                            app.ThorData.myStopCond = StopCond.None;
                        case 'Distance'
                            app.ThorData.myStopCond = StopCond.Distance;
                        case 'Degrees'
                            app.ThorData.myStopCond = StopCond.Degrees;
                        case 'Blue tape'
                            app.ThorData.myStopCond = StopCond.Blue;
                        case 'Red tape'
                            app.ThorData.myStopCond = StopCond.Red;
                    end
                case 'ErrorSignal'
                    switch(val)
                        case 'Left sensor'
                            app.ThorData.myErrorSignal = ErrorSignal.Left;
                        case 'Both sensors'
                            app.ThorData.myErrorSignal = ErrorSignal.Straight;
                        case 'Right sensor'
                            app.ThorData.myErrorSignal = ErrorSignal.Right;
                    end
                case 'Task'
                    switch(val)
                        case 'LowLevel'
                            app.ThorData.myTask = Task.LowLevel;
                            app.ThorTab_CommandBeingExecutedText.Text = 'Low level';
                        case 'Outer loop - whole'
                            app.ThorData.myTask = Task.DriveOuterLoopWhole;
                            app.ThorTab_CommandBeingExecutedText.Text = 'Drive the outer loop (whole)';
                        case 'Outer loop - left'
                            app.ThorData.myTask = Task.DriveOuterLoopLeft;
                            app.ThorTab_CommandBeingExecutedText.Text = 'Drive the outer loop (left)';
                        case 'Outer loop - right'
                            app.ThorData.myTask = Task.DriveOuterLoopRight;
                            app.ThorTab_CommandBeingExecutedText.Text = 'Drive the outer loop (right)';
                        case 'Inner loop - whole'
                            app.ThorData.myTask = Task.DriveInnerLoopWhole;
                            app.ThorTab_CommandBeingExecutedText.Text = 'Drive the inner loop (whole)';
                        case 'Inner loop - left'
                            app.ThorData.myTask = Task.DriveInnerLoopLeft;
                            app.ThorTab_CommandBeingExecutedText.Text = 'Drive the inner loop (left)';
                        case 'Inner loop - right'
                            app.ThorData.myTask = Task.DriveInnerLoopRight;
                            app.ThorTab_CommandBeingExecutedText.Text = 'Drive the inner loop (right)';
                        case 'Random'
                            app.ThorData.myTask = Task.DriveRandom;
                            app.ThorTab_CommandBeingExecutedText.Text = 'Drive randomly';
                        case 'Park'
                            app.ThorData.myTask = Task.Park;
                            app.ThorTab_CommandBeingExecutedText.Text = 'Park';
                    end
                case 'PacketNum'
                    app.ThorData.IncrementPacketNum = 1;
            end
            app.Notify();
            app.ThorData.IncrementPacketNum = 0;
        end
        
        function UpdateHulkData(app, name, val)
            %disp('Inside UpdateHulkData.')
            switch(name)
                case 'Connected'
                    app.HulkData.Connected = val;
                    app.QATab_Hulk_ConnectButton.Value = val;
                    app.HulkTab_ConnectButton.Value = val;
                    if (val)
                        app.QATab_Hulk_ConnectedLamp.Color = 'green';
                        app.HulkTab_ConnectedLamp.Color = 'green';
                    else
                        app.QATab_Hulk_ConnectedLamp.Color = 'red';
                        app.HulkTab_ConnectedLamp.Color = 'red';
                    end
                case 'Speed'
                    app.HulkData.Speed = val;
                    app.QATab_Hulk_SetSpeedSlider.Value = val;
                    app.QATab_Hulk_SetSpeedNumeric.Value = val;
                    app.HulkTab_SetSpeedSlider.Value = val;
                case 'FollowDistance'
                    app.HulkData.FollowDistance = val;
                    app.QATab_Hulk_SetFollowingDistanceSlider.Value = val;
                    app.QATab_Hulk_SetFollowingDistanceNumeric.Value = val;
                    app.HulkTab_SetFollowingDistanceSlider.Value = val;
                case 'LineFollow'
                    app.HulkData.LineFollow = val;
                    app.HulkTab_LinefollowingButton.Value = val;
                    %disp('app.HulkTab_LinefollowingButton.Value');
                    %disp(app.HulkTab_LinefollowingButton.Value);
                case 'Stop_Distance'
                    app.HulkData.Stop_Distance = val;
                    app.HulkTab_StopDistanceField.Value = val;
                case 'Stop_Degrees'
                    app.HulkData.Stop_Degrees = val;
                    app.HulkTab_StopDegreesField.Value = val;
                case 'StopCond'
                    switch(val)
                        case 'None'
                            app.HulkData.myStopCond = StopCond.None;
                        case 'Distance'
                            app.HulkData.myStopCond = StopCond.Distance;
                        case 'Degrees'
                            app.HulkData.myStopCond = StopCond.Degrees;
                        case 'Blue tape'
                            app.HulkData.myStopCond = StopCond.Blue;
                        case 'Red tape'
                            app.HulkData.myStopCond = StopCond.Red;
                    end
                case 'ErrorSignal'
                    switch(val)
                        case 'Left sensor'
                            app.HulkData.myErrorSignal = ErrorSignal.Left;
                        case 'Both sensors'
                            app.HulkData.myErrorSignal = ErrorSignal.Straight;
                        case 'Right sensor'
                            app.HulkData.myErrorSignal = ErrorSignal.Right;
                    end
                case 'Task'
                    switch(val)
                        case 'LowLevel'
                            app.HulkData.myTask = Task.LowLevel;
                            app.HulkTab_CommandBeingExecutedText.Text = 'Low level';
                        case 'Outer loop - whole'
                            app.HulkData.myTask = Task.DriveOuterLoopWhole;
                            app.HulkTab_CommandBeingExecutedText.Text = 'Drive the outer loop (whole)';
                        case 'Outer loop - left'
                            app.HulkData.myTask = Task.DriveOuterLoopLeft;
                            app.HulkTab_CommandBeingExecutedText.Text = 'Drive the outer loop (left)';
                        case 'Outer loop - right'
                            app.HulkData.myTask = Task.DriveOuterLoopRight;
                            app.HulkTab_CommandBeingExecutedText.Text = 'Drive the outer loop (right)';
                        case 'Inner loop - whole'
                            app.HulkData.myTask = Task.DriveInnerLoopWhole;
                            app.HulkTab_CommandBeingExecutedText.Text = 'Drive the inner loop (whole)';
                        case 'Inner loop - left'
                            app.HulkData.myTask = Task.DriveInnerLoopLeft;
                            app.HulkTab_CommandBeingExecutedText.Text = 'Drive the inner loop (left)';
                        case 'Inner loop - right'
                            app.HulkData.myTask = Task.DriveInnerLoopRight;
                            app.HulkTab_CommandBeingExecutedText.Text = 'Drive the inner loop (right)';
                        case 'Random'
                            app.HulkData.myTask = Task.DriveRandom;
                            app.HulkTab_CommandBeingExecutedText.Text = 'Drive randomly';
                        case 'Park'
                            app.HulkData.myTask = Task.Park;
                            app.HulkTab_CommandBeingExecutedText.Text = 'Park';
                    end
                case 'PacketNum'
                    app.HulkData.IncrementPacketNum = 1;
            end
            app.Notify();
            app.HulkData.IncrementPacketNum = 0;
        end
    
        function UpdateCaptainAmericaData(app, name, val)
            %disp('Inside UpdateCaptainAmericaData.')
            switch(name)
                case 'Connected'
                    app.CaptainAmericaData.Connected = val;
                    app.QATab_CaptainAmerica_ConnectButton.Value = val;
                    app.CaptainAmericaTab_ConnectButton.Value = val;
                    if (val)
                        app.QATab_CaptainAmerica_ConnectedLamp.Color = 'green';
                        app.CaptainAmericaTab_ConnectedLamp.Color = 'green';
                    else
                        app.QATab_CaptainAmerica_ConnectedLamp.Color = 'red';
                        app.CaptainAmericaTab_ConnectedLamp.Color = 'red';
                    end
                case 'Speed'
                    app.CaptainAmericaData.Speed = val;
                    app.QATab_CaptainAmerica_SetSpeedSlider.Value = val;
                    app.QATab_CaptainAmerica_SetSpeedNumeric.Value = val;
                    app.CaptainAmericaTab_SetSpeedSlider.Value = val;
                case 'FollowDistance'
                    app.CaptainAmericaData.FollowDistance = val;
                    app.QATab_CaptainAmerica_SetFollowingDistanceSlider.Value = val;
                    app.QATab_CaptainAmerica_SetFollowingDistanceNumeric.Value = val;
                    app.CaptainAmericaTab_SetFollowingDistanceSlider.Value = val;
                case 'LineFollow'
                    app.CaptainAmericaData.LineFollow = val;
                    app.CaptainAmericaTab_LinefollowingButton.Value = val;
                    %disp('app.CaptainAmericaTab_LinefollowingButton.Value');
                    %disp(app.CaptainAmericaTab_LinefollowingButton.Value);
                case 'Stop_Distance'
                    app.CaptainAmericaData.Stop_Distance = val;
                    app.CaptainAmericaTab_StopDistanceField.Value = val;
                case 'Stop_Degrees'
                    app.CaptainAmericaData.Stop_Degrees = val;
                    app.CaptainAmericaTab_StopDegreesField.Value = val;
                case 'StopCond'
                    switch(val)
                        case 'None'
                            app.CaptainAmericaData.myStopCond = StopCond.None;
                        case 'Distance'
                            app.CaptainAmericaData.myStopCond = StopCond.Distance;
                        case 'Degrees'
                            app.CaptainAmericaData.myStopCond = StopCond.Degrees;
                        case 'Blue tape'
                            app.CaptainAmericaData.myStopCond = StopCond.Blue;
                        case 'Red tape'
                            app.CaptainAmericaData.myStopCond = StopCond.Red;
                    end
                case 'ErrorSignal'
                    switch(val)
                        case 'Left sensor'
                            app.CaptainAmericaData.myErrorSignal = ErrorSignal.Left;
                        case 'Both sensors'
                            app.CaptainAmericaData.myErrorSignal = ErrorSignal.Straight;
                        case 'Right sensor'
                            app.CaptainAmericaData.myErrorSignal = ErrorSignal.Right;
                    end
                case 'Task'
                    switch(val)
                        case 'LowLevel'
                            app.CaptainAmericaData.myTask = Task.LowLevel;
                            app.CaptainAmericaTab_CommandBeingExecutedText.Text = 'Low level';
                        case 'Outer loop - whole'
                            app.CaptainAmericaData.myTask = Task.DriveOuterLoopWhole;
                            app.CaptainAmericaTab_CommandBeingExecutedText.Text = 'Drive the outer loop (whole)';
                        case 'Outer loop - left'
                            app.CaptainAmericaData.myTask = Task.DriveOuterLoopLeft;
                            app.CaptainAmericaTab_CommandBeingExecutedText.Text = 'Drive the outer loop (left)';
                        case 'Outer loop - right'
                            app.CaptainAmericaData.myTask = Task.DriveOuterLoopRight;
                            app.CaptainAmericaTab_CommandBeingExecutedText.Text = 'Drive the outer loop (right)';
                        case 'Inner loop - whole'
                            app.CaptainAmericaData.myTask = Task.DriveInnerLoopWhole;
                            app.CaptainAmericaTab_CommandBeingExecutedText.Text = 'Drive the inner loop (whole)';
                        case 'Inner loop - left'
                            app.CaptainAmericaData.myTask = Task.DriveInnerLoopLeft;
                            app.CaptainAmericaTab_CommandBeingExecutedText.Text = 'Drive the inner loop (left)';
                        case 'Inner loop - right'
                            app.CaptainAmericaData.myTask = Task.DriveInnerLoopRight;
                            app.CaptainAmericaTab_CommandBeingExecutedText.Text = 'Drive the inner loop (right)';
                        case 'Random'
                            app.CaptainAmericaData.myTask = Task.DriveRandom;
                            app.CaptainAmericaTab_CommandBeingExecutedText.Text = 'Drive randomly';
                        case 'Park'
                            app.CaptainAmericaData.myTask = Task.Park;
                            app.CaptainAmericaTab_CommandBeingExecutedText.Text = 'Park';
                    end
                case 'PacketNum'
                    app.CaptainAmericaData.IncrementPacketNum = 1;
            end
            app.Notify();
            app.CaptainAmericaData.IncrementPacketNum = 0;
        end
    
    end
    
    methods (Access = public)
        
        function UpdateDataFromEV3(app, Name, EV3)
            %disp('Inside UpdateDataFromEV3.');
            switch(Name)
                case 'Iron Man'
                    app.IronManTab_LeftSensorVal.Text = int2str(EV3.Left);
                    switch(EV3.Middle)
                        case EV3_Colors.None
                            app.IronManTab_MiddleLamp.Color = [0.94 0.94 0.94];
                        case EV3_Colors.Black
                            app.IronManTab_MiddleLamp.Color = 'black';
                        case EV3_Colors.Blue
                            app.IronManTab_MiddleLamp.Color = 'blue';
                        case EV3_Colors.Green
                            app.IronManTab_MiddleLamp.Color = 'green';
                        case EV3_Colors.Yellow
                            app.IronManTab_MiddleLamp.Color = 'yellow';
                        case EV3_Colors.Red
                            app.IronManTab_MiddleLamp.Color = 'red';
                        case EV3_Colors.White
                            app.IronManTab_MiddleLamp.Color = 'white';
                        case EV3_Colors.Brown
                            app.IronManTab_MiddleLamp.Color = 'black';
                    end
                    app.IronManTab_RightSensorVal.Text = int2str(EV3.Right);
                    app.IronManTab_UltrasonicSensorGauge.Value = EV3.Ultrasonic;
                    app.IronManTab_SpeedometerGauge.Value = EV3.Speed;
                    app.IronManTab_BatteryVoltageValue.Text = sprintf('%1.1fV', single(EV3.BatteryVoltage)/10);
                    if (app.IronManTab_DataLogButton.Value)
                        if ( (toc - app.IronManClock) > 0.95 )
                            %fprintf(fid,'%d,%d,%0.3f\n',app.RLI_val(1,x),app.Ultra(1,x),app.ts(1,x));
                            fprintf(app.IronManFileID, '%d,%d,%0.3f\n', int8(EV3.Left), int8(EV3.Ultrasonic), double(toc));
                            app.IronManClock = toc;
                        end
                    end
                case 'Thor'
                    app.ThorTab_LeftSensorVal.Text = int2str(EV3.Left);
                    switch(EV3.Middle)
                        case EV3_Colors.None
                            app.ThorTab_MiddleLamp.Color = [0.94 0.94 0.94];
                        case EV3_Colors.Black
                            app.ThorTab_MiddleLamp.Color = 'black';
                        case EV3_Colors.Blue
                            app.ThorTab_MiddleLamp.Color = 'blue';
                        case EV3_Colors.Green
                            app.ThorTab_MiddleLamp.Color = 'green';
                        case EV3_Colors.Yellow
                            app.ThorTab_MiddleLamp.Color = 'yellow';
                        case EV3_Colors.Red
                            app.ThorTab_MiddleLamp.Color = 'red';
                        case EV3_Colors.White
                            app.ThorTab_MiddleLamp.Color = 'white';
                        case EV3_Colors.Brown
                            app.ThorTab_MiddleLamp.Color = 'black';
                    end
                    app.ThorTab_RightSensorVal.Text = int2str(EV3.Right);
                    app.ThorTab_UltrasonicSensorGauge.Value = EV3.Ultrasonic;
                    app.ThorTab_SpeedometerGauge.Value = EV3.Speed;
                    app.ThorTab_BatteryVoltageValue.Text = sprintf('%1.1fV', single(EV3.BatteryVoltage)/10);
                    if (app.ThorTab_DataLogButton.Value)
                        if ( (toc - app.ThorClock) > 0.95 )
                            %fprintf(fid,'%d,%d,%0.3f\n',app.RLI_val(1,x),app.Ultra(1,x),app.ts(1,x));
                            fprintf(app.ThorFileID, '%d,%d,%0.3f\n', int8(EV3.Left), int8(EV3.Ultrasonic), double(toc));
                            app.ThorClock = toc;
                        end
                    end
                case 'Hulk'
                    app.HulkTab_LeftSensorVal.Text = int2str(EV3.Left);
                    switch(EV3.Middle)
                        case EV3_Colors.None
                            app.HulkTab_MiddleLamp.Color = [0.94 0.94 0.94];
                        case EV3_Colors.Black
                            app.HulkTab_MiddleLamp.Color = 'black';
                        case EV3_Colors.Blue
                            app.HulkTab_MiddleLamp.Color = 'blue';
                        case EV3_Colors.Green
                            app.HulkTab_MiddleLamp.Color = 'green';
                        case EV3_Colors.Yellow
                            app.HulkTab_MiddleLamp.Color = 'yellow';
                        case EV3_Colors.Red
                            app.HulkTab_MiddleLamp.Color = 'red';
                        case EV3_Colors.White
                            app.HulkTab_MiddleLamp.Color = 'white';
                        case EV3_Colors.Brown
                            app.HulkTab_MiddleLamp.Color = 'black';
                    end
                    app.HulkTab_RightSensorVal.Text = int2str(EV3.Right);
                    app.HulkTab_UltrasonicSensorGauge.Value = EV3.Ultrasonic;
                    app.HulkTab_SpeedometerGauge.Value = EV3.Speed;
                    app.HulkTab_BatteryVoltageValue.Text = sprintf('%1.1fV', single(EV3.BatteryVoltage)/10);
                    if (app.HulkTab_DataLogButton.Value)
                        if ( (toc - app.HulkClock) > 0.95 )
                            %fprintf(fid,'%d,%d,%0.3f\n',app.RLI_val(1,x),app.Ultra(1,x),app.ts(1,x));
                            fprintf(app.HulkFileID, '%d,%d,%0.3f\n', int8(EV3.Left), int8(EV3.Ultrasonic), double(toc));
                            app.HulkClock = toc;
                        end
                    end
                case 'Captain America'
                    app.CaptainAmericaTab_LeftSensorVal.Text = int2str(EV3.Left);
                    switch(EV3.Middle)
                        case EV3_Colors.None
                            app.CaptainAmericaTab_MiddleLamp.Color = [0.94 0.94 0.94];
                        case EV3_Colors.Black
                            app.CaptainAmericaTab_MiddleLamp.Color = 'black';
                        case EV3_Colors.Blue
                            app.CaptainAmericaTab_MiddleLamp.Color = 'blue';
                        case EV3_Colors.Green
                            app.CaptainAmericaTab_MiddleLamp.Color = 'green';
                        case EV3_Colors.Yellow
                            app.CaptainAmericaTab_MiddleLamp.Color = 'yellow';
                        case EV3_Colors.Red
                            app.CaptainAmericaTab_MiddleLamp.Color = 'red';
                        case EV3_Colors.White
                            app.CaptainAmericaTab_MiddleLamp.Color = 'white';
                        case EV3_Colors.Brown
                            app.CaptainAmericaTab_MiddleLamp.Color = 'black';
                    end
                    app.CaptainAmericaTab_RightSensorVal.Text = int2str(EV3.Right);
                    app.CaptainAmericaTab_UltrasonicSensorGauge.Value = EV3.Ultrasonic;
                    app.CaptainAmericaTab_SpeedometerGauge.Value = EV3.Speed;
                    app.CaptainAmericaTab_BatteryVoltageValue.Text = sprintf('%1.1fV', single(EV3.BatteryVoltage)/10);
                    if (app.CaptainAmericaTab_DataLogButton.Value)
                        if ( (toc - app.CaptainAmericaClock) > 0.95 )
                            %fprintf(fid,'%d,%d,%0.3f\n',app.RLI_val(1,x),app.Ultra(1,x),app.ts(1,x));
                            fprintf(app.CaptainAmericaFileID, '%d,%d,%0.3f\n', int8(EV3.Left), int8(EV3.Ultrasonic), double(toc));
                            app.CaptainAmericaClock = toc;
                        end
                    end
            end
        end    
    
        function AddObserver(app, obs)
            %disp('Inside AddObserver.');
            app.ObserverList = [app.ObserverList, obs];
            app.Notify();
        end
    
        function UpdateTrackableArray(app, TrackableArray)
            for x = 1:length(TrackableArray)
                app.Draw(TrackableArray(x).Name, TrackableArray(x).x, TrackableArray(x).z, TrackableArray(x).yaw);
            end
        end
        
        function Map_Click(app, source, event)
            disp('Inside Map_Click.');
            Temp_Figure = figure;
            Temp_Axes = axes;
            Temp_Image = imread('iSpaceMapV2.0_BIG.png');
            Temp_Image_Scaled = imref2d(size(Temp_Image));
            Temp_Axes = imshow(Temp_Image, Temp_Image_Scaled);
            coordinates = ginput;
            coordinates_pixel_x = coordinates(:,1);
            coordinates(:,1) = ((coordinates(:,2)./13312.*469)-159)./132;
            coordinates(:,2) = (159-(coordinates_pixel_x(:,1)./17923.*631))./132;
            disp(coordinates);
            close(Temp_Figure);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Starting up.');
            InitializeMap(app);
            InitializeVariables(app);
            InitializeLines(app);
            %InitializeOptiTrack(app);
            %InitializeTimer(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disp('Shutting down.')
            delete(app)
        end

        % Value changed function: IronManTab_ConnectButton
        function IronManTab_ConnectButtonValueChanged(app, event)
            value = app.IronManTab_ConnectButton.Value;
            app.UpdateIronManData('Connected', value);
        end

        % Value changed function: QATab_IronMan_ConnectButton
        function QATab_IronMan_ConnectButtonValueChanged(app, event)
            value = app.QATab_IronMan_ConnectButton.Value;
            app.UpdateIronManData('Connected', value);            
        end

        % Value changing function: QATab_IronMan_SetSpeedSlider
        function QATab_IronMan_SetSpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateIronManData('Speed',changingValue);
        end

        % Value changed function: QATab_IronMan_SetSpeedNumeric
        function QATab_IronMan_SetSpeedNumericValueChanged(app, event)
            value = app.QATab_IronMan_SetSpeedNumeric.Value;
            app.UpdateIronManData('Speed',value);
        end

        % Value changing function: 
        % QATab_IronMan_SetFollowingDistanceSlider
        function QATab_IronMan_SetFollowingDistanceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateIronManData('FollowDistance',changingValue);
        end

        % Value changed function: 
        % QATab_IronMan_SetFollowingDistanceNumeric
        function QATab_IronMan_SetFollowingDistanceNumericValueChanged(app, event)
            value = app.QATab_IronMan_SetFollowingDistanceNumeric.Value;
            app.UpdateIronManData('FollowDistance',value);
        end

        % Value changing function: IronManTab_SetSpeedSlider
        function IronManTab_SetSpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateIronManData('Speed',changingValue);
        end

        % Value changing function: 
        % IronManTab_SetFollowingDistanceSlider
        function IronManTab_SetFollowingDistanceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateIronManData('FollowDistance',changingValue);
        end

        % Button pushed function: IronManTab_UpdateHighCommandButton
        function IronManTab_UpdateHighCommandButtonPushed(app, event)
            if (app.IronManTab_ParkButton.Value)
                app.UpdateIronManData('Task', 'Park');  
            else
                app.UpdateIronManData('Task', app.IronManTab_DriveTask.Value);
            end
        end

        % Selection changed function: IronManTab_Task
        function IronManTab_TaskSelectionChanged(app, event)
            selectedButton = app.IronManTab_Task.SelectedObject;
            if strcmp(selectedButton.Text, 'Park')
                app.IronManTab_DriveTask.Visible = 'off';
            else
                app.IronManTab_DriveTask.Visible = 'on';
            end
        end

        % Button pushed function: IronManTab_UpdateLowCommandButton
        function IronManTab_UpdateLowCommandButtonPushed(app, event)
            app.UpdateIronManData('Stop_Distance',app.IronManTab_StopDistanceField.Value);
            app.UpdateIronManData('Stop_Degrees',app.IronManTab_StopDegreesField.Value);
            app.UpdateIronManData('StopCond',app.IronManTab_StopCondition.SelectedObject.Text);
            app.UpdateIronManData('ErrorSignal',app.IronManTab_ErrorSignal.SelectedObject.Text);
            app.UpdateIronManData('LineFollow', app.IronManTab_LinefollowingButton.Value);
            app.UpdateIronManData('Task','LowLevel');
            app.UpdateIronManData('PacketNum', 1);
        end

        % Button pushed function: STOPALLButton
        function STOPALLButtonPushed(app, event)
            app.UpdateAllData('Speed',0);
        end

        % Value changed function: CONNECTALLButton
        function CONNECTALLButtonValueChanged(app, event)
            value = app.CONNECTALLButton.Value;
            app.UpdateAllData('Connected',value);
        end

        % Value changing function: SETALLSPEEDScmsSlider
        function SETALLSPEEDScmsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateAllData('Speed', changingValue);
        end

        % Value changed function: UpdateAll_SetSpeedNumeric
        function UpdateAll_SetSpeedNumericValueChanged(app, event)
            value = app.UpdateAll_SetSpeedNumeric.Value;
            app.UpdateAllData('Speed', value);
        end

        % Value changing function: SETALLFOLLOWINGDISTcmSlider
        function SETALLFOLLOWINGDISTcmSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateAllData('FollowDistance', changingValue);
        end

        % Value changed function: 
        % UpdateAll_SetFollowingDistanceNumeric
        function UpdateAll_SetFollowingDistanceNumericValueChanged(app, event)
            value = app.UpdateAll_SetFollowingDistanceNumeric.Value;
            app.UpdateAllData('FollowDistance', value);
        end

        % Callback function
        function Pixel_HeightEditFieldValueChanged(app, event)
            value = app.Pixel_HeightEditField.Value;
            set(app.TestLine,'YData',(value*132+159))
        end

        % Callback function
        function Pixel_WidthEditFieldValueChanged(app, event)
            value = app.Pixel_WidthEditField.Value;
            set(app.TestLine,'XData',(160-(value*132)))
        end

        % Value changed function: IronManTab_DataLogButton
        function IronManTab_DataLogButtonValueChanged(app, event)
            value = app.IronManTab_DataLogButton.Value;
            switch (value)
                case 0
                    app.IronManTab_DataloggingLamp.Color = 'red';
                    fclose(app.IronManFileID);
                    %filename = string(app.TextArea.Value);
                    %task1 = csvread(filename);
                    %[C1, C2, C3] = EV3_eval_source(task1(:,3),task1(:,1),task1(:,2));
                    %app.C1_text.Text = int2str(C1);
                    %app.C2_text.Text = int2str(C2);
                    %app.C3_text.Text = int2str(C3);
                    FileRead = csvread(app.IronManFileName);
                    [C1, C2, C3] = EV3_eval_source(FileRead(:,3), FileRead(:,1), FileRead(:,2));
                    app.IronManTab_C1Val.Text = int2str(C1);
                    app.IronManTab_C2Val.Text = int2str(C2);
                    app.IronManTab_C3Val.Text = int2str(C3);
                case 1
                    app.IronManTab_DataloggingLamp.Color = 'green';
                    app.IronManFileName = sprintf('IronMan%s','.csv');
                    app.IronManFileID = fopen(app.IronManFileName, 'w');
                    app.IronManClock = toc;
            end
        end

        % Value changed function: QATab_Thor_ConnectButton
        function QATab_Thor_ConnectButtonValueChanged(app, event)
            value = app.QATab_Thor_ConnectButton.Value;
            app.UpdateThorData('Connected', value);
        end

        % Value changing function: QATab_Thor_SetSpeedSlider
        function QATab_Thor_SetSpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateThorData('Speed',changingValue);
        end

        % Value changed function: QATab_Thor_SetSpeedNumeric
        function QATab_Thor_SetSpeedNumericValueChanged(app, event)
            value = app.QATab_Thor_SetSpeedNumeric.Value;
            app.UpdateThorData('Speed',value);
        end

        % Value changing function: 
        % QATab_Thor_SetFollowingDistanceSlider
        function QATab_Thor_SetFollowingDistanceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateThorData('FollowDistance',changingValue);
        end

        % Value changed function: 
        % QATab_Thor_SetFollowingDistanceNumeric
        function QATab_Thor_SetFollowingDistanceNumericValueChanged(app, event)
            value = app.QATab_Thor_SetFollowingDistanceNumeric.Value;
            app.UpdateThorData('FollowDistance',value);
        end

        % Value changed function: ThorTab_ConnectButton
        function ThorTab_ConnectButtonValueChanged(app, event)
            value = app.ThorTab_ConnectButton.Value;
            app.UpdateThorData('Connected', value);
        end

        % Value changed function: ThorTab_DataLogButton
        function ThorTab_DataLogButtonValueChanged(app, event)
            value = app.ThorTab_DataLogButton.Value;
            switch (value)
                case 0
                    app.ThorTab_DataloggingLamp.Color = 'red';
                    fclose(app.ThorFileID);
                    %filename = string(app.TextArea.Value);
                    %task1 = csvread(filename);
                    %[C1, C2, C3] = EV3_eval_source(task1(:,3),task1(:,1),task1(:,2));
                    %app.C1_text.Text = int2str(C1);
                    %app.C2_text.Text = int2str(C2);
                    %app.C3_text.Text = int2str(C3);
                    FileRead = csvread(app.ThorFileName);
                    [C1, C2, C3] = EV3_eval_source(FileRead(:,3), FileRead(:,1), FileRead(:,2));
                    app.ThorTab_C1Val.Text = int2str(C1);
                    app.ThorTab_C2Val.Text = int2str(C2);
                    app.ThorTab_C3Val.Text = int2str(C3);
                case 1
                    app.ThorTab_DataloggingLamp.Color = 'green';
                    app.ThorFileName = sprintf('Thor%s','.csv');
                    app.ThorFileID = fopen(app.ThorFileName, 'w');
                    app.ThorClock = toc;
            end
        end

        % Value changing function: ThorTab_SetSpeedSlider
        function ThorTab_SetSpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateThorData('Speed',changingValue);
        end

        % Value changing function: ThorTab_SetFollowingDistanceSlider
        function ThorTab_SetFollowingDistanceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateThorData('FollowDistance',changingValue);
        end

        % Selection changed function: ThorTab_Task
        function ThorTab_TaskSelectionChanged(app, event)
            selectedButton = app.ThorTab_Task.SelectedObject;
            if strcmp(selectedButton.Text, 'Park')
                app.ThorTab_DriveTask.Visible = 'off';
            else
                app.ThorTab_DriveTask.Visible = 'on';
            end
        end

        % Button pushed function: ThorTab_UpdateHighCommandButton
        function ThorTab_UpdateHighCommandButtonPushed(app, event)
            if (app.ThorTab_ParkButton.Value)
                app.UpdateThorData('Task', 'Park');    
            else
                app.UpdateThorData('Task', app.ThorTab_DriveTask.Value);
            end
        end

        % Button pushed function: ThorTab_UpdateLowCommandButton
        function ThorTab_UpdateLowCommandButtonPushed(app, event)
            app.UpdateThorData('Stop_Distance',app.ThorTab_StopDistanceField.Value);
            app.UpdateThorData('Stop_Degrees',app.ThorTab_StopDegreesField.Value);
            app.UpdateThorData('StopCond',app.ThorTab_StopCondition.SelectedObject.Text);
            app.UpdateThorData('ErrorSignal',app.ThorTab_ErrorSignal.SelectedObject.Text);
            app.UpdateThorData('LineFollow', app.ThorTab_LinefollowingButton.Value);
            app.UpdateThorData('Task','LowLevel');
            app.UpdateThorData('PacketNum', 1);
        end

        % Value changed function: QATab_Hulk_ConnectButton
        function QATab_Hulk_ConnectButtonValueChanged(app, event)
            value = app.QATab_Hulk_ConnectButton.Value;
            app.UpdateHulkData('Connected', value);
        end

        % Value changing function: QATab_Hulk_SetSpeedSlider
        function QATab_Hulk_SetSpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateHulkData('Speed',changingValue);
        end

        % Value changed function: QATab_Hulk_SetSpeedNumeric
        function QATab_Hulk_SetSpeedNumericValueChanged(app, event)
            value = app.QATab_Hulk_SetSpeedNumeric.Value;
            app.UpdateHulkData('Speed',value);
        end

        % Value changing function: 
        % QATab_Hulk_SetFollowingDistanceSlider
        function QATab_Hulk_SetFollowingDistanceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateHulkData('FollowDistance',changingValue);
        end

        % Value changed function: 
        % QATab_Hulk_SetFollowingDistanceNumeric
        function QATab_Hulk_SetFollowingDistanceNumericValueChanged(app, event)
            value = app.QATab_Hulk_SetFollowingDistanceNumeric.Value;
            app.UpdateHulkData('FollowDistance',value);
        end

        % Value changed function: HulkTab_ConnectButton
        function HulkTab_ConnectButtonValueChanged(app, event)
            value = app.HulkTab_ConnectButton.Value;
            app.UpdateHulkData('Connected', value);
        end

        % Value changed function: HulkTab_DataLogButton
        function HulkTab_DataLogButtonValueChanged(app, event)
            value = app.HulkTab_DataLogButton.Value;
            switch (value)
                case 0
                    app.HulkTab_DataloggingLamp.Color = 'red';
                    fclose(app.HulkFileID);
                    %filename = string(app.TextArea.Value);
                    %task1 = csvread(filename);
                    %[C1, C2, C3] = EV3_eval_source(task1(:,3),task1(:,1),task1(:,2));
                    %app.C1_text.Text = int2str(C1);
                    %app.C2_text.Text = int2str(C2);
                    %app.C3_text.Text = int2str(C3);
                    FileRead = csvread(app.HulkFileName);
                    [C1, C2, C3] = EV3_eval_source(FileRead(:,3), FileRead(:,1), FileRead(:,2));
                    app.HulkTab_C1Val.Text = int2str(C1);
                    app.HulkTab_C2Val.Text = int2str(C2);
                    app.HulkTab_C3Val.Text = int2str(C3);
                case 1
                    app.HulkTab_DataloggingLamp.Color = 'green';
                    app.HulkFileName = sprintf('Hulk%s','.csv');
                    app.HulkFileID = fopen(app.HulkFileName, 'w');
                    app.HulkClock = toc;
            end
        end

        % Value changing function: HulkTab_SetSpeedSlider
        function HulkTab_SetSpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateHulkData('Speed',changingValue);
        end

        % Value changing function: HulkTab_SetFollowingDistanceSlider
        function HulkTab_SetFollowingDistanceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateHulkData('FollowDistance',changingValue);
        end

        % Selection changed function: HulkTab_Task
        function HulkTab_TaskSelectionChanged(app, event)
            selectedButton = app.HulkTab_Task.SelectedObject;
            if strcmp(selectedButton.Text, 'Park')
                app.HulkTab_DriveTask.Visible = 'off';
            else
                app.HulkTab_DriveTask.Visible = 'on';
            end
        end

        % Button pushed function: HulkTab_UpdateHighCommandButton
        function HulkTab_UpdateHighCommandButtonPushed(app, event)
            if (app.HulkTab_ParkButton.Value)
                app.UpdateHulkData('Task', 'Park');    
            else
                app.UpdateHulkData('Task', app.HulkTab_DriveTask.Value);
            end
        end

        % Button pushed function: HulkTab_UpdateLowCommandButton
        function HulkTab_UpdateLowCommandButtonPushed(app, event)
            app.UpdateHulkData('Stop_Distance',app.HulkTab_StopDistanceField.Value);
            app.UpdateHulkData('Stop_Degrees',app.HulkTab_StopDegreesField.Value);
            app.UpdateHulkData('StopCond',app.HulkTab_StopCondition.SelectedObject.Text);
            app.UpdateHulkData('ErrorSignal',app.HulkTab_ErrorSignal.SelectedObject.Text);
            app.UpdateHulkData('LineFollow', app.HulkTab_LinefollowingButton.Value);
            app.UpdateHulkData('Task','LowLevel');
            app.UpdateHulkData('PacketNum', 1);
        end

        % Value changed function: QATab_CaptainAmerica_ConnectButton
        function QATab_CaptainAmerica_ConnectButtonValueChanged(app, event)
            value = app.QATab_CaptainAmerica_ConnectButton.Value;
            app.UpdateCaptainAmericaData('Connected', value);
        end

        % Value changing function: QATab_CaptainAmerica_SetSpeedSlider
        function QATab_CaptainAmerica_SetSpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateCaptainAmericaData('Speed',changingValue);
        end

        % Value changed function: QATab_CaptainAmerica_SetSpeedNumeric
        function QATab_CaptainAmerica_SetSpeedNumericValueChanged(app, event)
            value = app.QATab_CaptainAmerica_SetSpeedNumeric.Value;
            app.UpdateCaptainAmericaData('Speed',value);
        end

        % Value changing function: 
        % QATab_CaptainAmerica_SetFollowingDistanceSlider
        function QATab_CaptainAmerica_SetFollowingDistanceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateCaptainAmericaData('FollowDistance',changingValue);
        end

        % Value changed function: 
        % QATab_CaptainAmerica_SetFollowingDistanceNumeric
        function QATab_CaptainAmerica_SetFollowingDistanceNumericValueChanged(app, event)
            value = app.QATab_CaptainAmerica_SetFollowingDistanceNumeric.Value;
            app.UpdateCaptainAmericaData('FollowDistance',value);
        end

        % Value changed function: CaptainAmericaTab_ConnectButton
        function CaptainAmericaTab_ConnectButtonValueChanged(app, event)
            value = app.CaptainAmericaTab_ConnectButton.Value;
            app.UpdateCaptainAmericaData('Connected', value);
        end

        % Value changed function: CaptainAmericaTab_DataLogButton
        function CaptainAmericaTab_DataLogButtonValueChanged(app, event)
            value = app.CaptainAmericaTab_DataLogButton.Value;
            switch (value)
                case 0
                    app.CaptainAmericaTab_DataloggingLamp.Color = 'red';
                    fclose(app.CaptainAmericaFileID);
                    %filename = string(app.TextArea.Value);
                    %task1 = csvread(filename);
                    %[C1, C2, C3] = EV3_eval_source(task1(:,3),task1(:,1),task1(:,2));
                    %app.C1_text.Text = int2str(C1);
                    %app.C2_text.Text = int2str(C2);
                    %app.C3_text.Text = int2str(C3);
                    FileRead = csvread(app.CaptainAmericaFileName);
                    [C1, C2, C3] = EV3_eval_source(FileRead(:,3), FileRead(:,1), FileRead(:,2));
                    app.CaptainAmericaTab_C1Val.Text = int2str(C1);
                    app.CaptainAmericaTab_C2Val.Text = int2str(C2);
                    app.CaptainAmericaTab_C3Val.Text = int2str(C3);
                case 1
                    app.CaptainAmericaTab_DataloggingLamp.Color = 'green';
                    app.CaptainAmericaFileName = sprintf('CaptainAmerica%s','.csv');
                    app.CaptainAmericaFileID = fopen(app.CaptainAmericaFileName, 'w');
                    app.CaptainAmericaClock = toc;
            end
        end

        % Value changing function: CaptainAmericaTab_SetSpeedSlider
        function CaptainAmericaTab_SetSpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateCaptainAmericaData('Speed',changingValue);
        end

        % Value changing function: 
        % CaptainAmericaTab_SetFollowingDistanceSlider
        function CaptainAmericaTab_SetFollowingDistanceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateCaptainAmericaData('FollowDistance',changingValue);
        end

        % Selection changed function: CaptainAmericaTab_Task
        function CaptainAmericaTab_TaskSelectionChanged(app, event)
            selectedButton = app.CaptainAmericaTab_Task.SelectedObject;
            if strcmp(selectedButton.Text, 'Park')
                app.CaptainAmericaTab_DriveTask.Visible = 'off';
            else
                app.CaptainAmericaTab_DriveTask.Visible = 'on';
            end
        end

        % Button pushed function: 
        % CaptainAmericaTab_UpdateHighCommandButton
        function CaptainAmericaTab_UpdateHighCommandButtonPushed(app, event)
            if (app.CaptainAmericaTab_ParkButton.Value)
                app.UpdateCaptainAmericaData('Task', 'Park');    
            else
                app.UpdateCaptainAmericaData('Task', app.CaptainAmericaTab_DriveTask.Value);
            end
        end

        % Button pushed function: 
        % CaptainAmericaTab_UpdateLowCommandButton
        function CaptainAmericaTab_UpdateLowCommandButtonPushed(app, event)
            app.UpdateCaptainAmericaData('Stop_Distance',app.CaptainAmericaTab_StopDistanceField.Value);
            app.UpdateCaptainAmericaData('Stop_Degrees',app.CaptainAmericaTab_StopDegreesField.Value);
            app.UpdateCaptainAmericaData('StopCond',app.CaptainAmericaTab_StopCondition.SelectedObject.Text);
            app.UpdateCaptainAmericaData('ErrorSignal',app.CaptainAmericaTab_ErrorSignal.SelectedObject.Text);
            app.UpdateCaptainAmericaData('LineFollow', app.CaptainAmericaTab_LinefollowingButton.Value);
            app.UpdateCaptainAmericaData('Task','LowLevel');
            app.UpdateCaptainAmericaData('PacketNum', 1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.STOPALLButton = uibutton(app.UIFigure, 'push');
            app.STOPALLButton.ButtonPushedFcn = createCallbackFcn(app, @STOPALLButtonPushed, true);
            app.STOPALLButton.Text = 'STOP ALL';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.QuickAdjustTab = uitab(app.TabGroup);
            app.QuickAdjustTab.Title = 'Quick Adjust';
            app.IronManPanel = uipanel(app.QuickAdjustTab);
            app.IronManPanel.Title = 'Iron Man';
            app.SetSpeedcmsLabel = uilabel(app.IronManPanel);
            app.SetSpeedcmsLabel.Text = 'Set Speed (cm/s)';
            app.QATab_IronMan_SetSpeedSlider = uislider(app.IronManPanel);
            app.QATab_IronMan_SetSpeedSlider.Limits = [-100 100];
            app.QATab_IronMan_SetSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @QATab_IronMan_SetSpeedSliderValueChanging, true);
            app.SetFollowingDistancecmLabel = uilabel(app.IronManPanel);
            app.SetFollowingDistancecmLabel.Text = 'Set Following Distance (cm)';
            app.QATab_IronMan_SetFollowingDistanceSlider = uislider(app.IronManPanel);
            app.QATab_IronMan_SetFollowingDistanceSlider.Limits = [0 255];
            app.QATab_IronMan_SetFollowingDistanceSlider.MajorTicks = [0 64 128 192 255];
            app.QATab_IronMan_SetFollowingDistanceSlider.ValueChangingFcn = createCallbackFcn(app, @QATab_IronMan_SetFollowingDistanceSliderValueChanging, true);
            app.ConnectedLampLabel = uilabel(app.IronManPanel);
            app.ConnectedLampLabel.Text = 'Connected';
            app.QATab_IronMan_ConnectedLamp = uilamp(app.IronManPanel);
            app.QATab_IronMan_ConnectButton = uibutton(app.IronManPanel, 'state');
            app.QATab_IronMan_ConnectButton.ValueChangedFcn = createCallbackFcn(app, @QATab_IronMan_ConnectButtonValueChanged, true);
            app.QATab_IronMan_ConnectButton.Text = 'Connect';
            app.QATab_IronMan_SetSpeedNumeric = uieditfield(app.IronManPanel, 'numeric');
            app.QATab_IronMan_SetSpeedNumeric.Limits = [-100 100];
            app.QATab_IronMan_SetSpeedNumeric.RoundFractionalValues = 'on';
            app.QATab_IronMan_SetSpeedNumeric.ValueChangedFcn = createCallbackFcn(app, @QATab_IronMan_SetSpeedNumericValueChanged, true);
            app.QATab_IronMan_SetFollowingDistanceNumeric = uieditfield(app.IronManPanel, 'numeric');
            app.QATab_IronMan_SetFollowingDistanceNumeric.Limits = [0 255];
            app.QATab_IronMan_SetFollowingDistanceNumeric.RoundFractionalValues = 'on';
            app.QATab_IronMan_SetFollowingDistanceNumeric.ValueChangedFcn = createCallbackFcn(app, @QATab_IronMan_SetFollowingDistanceNumericValueChanged, true);
            app.UPDATEALLPanel = uipanel(app.QuickAdjustTab);
            app.UPDATEALLPanel.Title = 'UPDATE ALL';
            app.SETALLSPEEDScmsSliderLabel = uilabel(app.UPDATEALLPanel);
            app.SETALLSPEEDScmsSliderLabel.Text = 'SET ALL SPEEDS (cm/s)';
            app.SETALLSPEEDScmsSlider = uislider(app.UPDATEALLPanel);
            app.SETALLSPEEDScmsSlider.Limits = [-100 100];
            app.SETALLSPEEDScmsSlider.ValueChangingFcn = createCallbackFcn(app, @SETALLSPEEDScmsSliderValueChanging, true);
            app.SETALLFOLLOWINGDISTcmSliderLabel = uilabel(app.UPDATEALLPanel);
            app.SETALLFOLLOWINGDISTcmSliderLabel.Text = 'SET ALL FOLLOWING DIST (cm)';
            app.SETALLFOLLOWINGDISTcmSlider = uislider(app.UPDATEALLPanel);
            app.SETALLFOLLOWINGDISTcmSlider.Limits = [0 255];
            app.SETALLFOLLOWINGDISTcmSlider.MajorTicks = [0 64 128 192 255];
            app.SETALLFOLLOWINGDISTcmSlider.ValueChangingFcn = createCallbackFcn(app, @SETALLFOLLOWINGDISTcmSliderValueChanging, true);
            app.CONNECTALLButton = uibutton(app.UPDATEALLPanel, 'state');
            app.CONNECTALLButton.ValueChangedFcn = createCallbackFcn(app, @CONNECTALLButtonValueChanged, true);
            app.CONNECTALLButton.Text = 'CONNECT ALL';
            app.UpdateAll_SetSpeedNumeric = uieditfield(app.UPDATEALLPanel, 'numeric');
            app.UpdateAll_SetSpeedNumeric.Limits = [-100 100];
            app.UpdateAll_SetSpeedNumeric.RoundFractionalValues = 'on';
            app.UpdateAll_SetSpeedNumeric.ValueChangedFcn = createCallbackFcn(app, @UpdateAll_SetSpeedNumericValueChanged, true);
            app.UpdateAll_SetFollowingDistanceNumeric = uieditfield(app.UPDATEALLPanel, 'numeric');
            app.UpdateAll_SetFollowingDistanceNumeric.Limits = [0 255];
            app.UpdateAll_SetFollowingDistanceNumeric.RoundFractionalValues = 'on';
            app.UpdateAll_SetFollowingDistanceNumeric.ValueChangedFcn = createCallbackFcn(app, @UpdateAll_SetFollowingDistanceNumericValueChanged, true);
            app.ThorPanel = uipanel(app.QuickAdjustTab);
            app.ThorPanel.Title = 'Thor';
            app.SetSpeedcmsLabel_2 = uilabel(app.ThorPanel);
            app.SetSpeedcmsLabel_2.Text = 'Set Speed (cm/s)';
            app.QATab_Thor_SetSpeedSlider = uislider(app.ThorPanel);
            app.QATab_Thor_SetSpeedSlider.Limits = [-100 100];
            app.QATab_Thor_SetSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @QATab_Thor_SetSpeedSliderValueChanging, true);
            app.SetFollowingDistancecmLabel_2 = uilabel(app.ThorPanel);
            app.SetFollowingDistancecmLabel_2.Text = 'Set Following Distance (cm)';
            app.QATab_Thor_SetFollowingDistanceSlider = uislider(app.ThorPanel);
            app.QATab_Thor_SetFollowingDistanceSlider.Limits = [0 255];
            app.QATab_Thor_SetFollowingDistanceSlider.MajorTicks = [0 64 128 192 255];
            app.QATab_Thor_SetFollowingDistanceSlider.ValueChangingFcn = createCallbackFcn(app, @QATab_Thor_SetFollowingDistanceSliderValueChanging, true);
            app.ConnectedLamp_2Label = uilabel(app.ThorPanel);
            app.ConnectedLamp_2Label.Text = 'Connected';
            app.QATab_Thor_ConnectedLamp = uilamp(app.ThorPanel);
            app.QATab_Thor_ConnectButton = uibutton(app.ThorPanel, 'state');
            app.QATab_Thor_ConnectButton.ValueChangedFcn = createCallbackFcn(app, @QATab_Thor_ConnectButtonValueChanged, true);
            app.QATab_Thor_ConnectButton.Text = 'Connect';
            app.QATab_Thor_SetSpeedNumeric = uieditfield(app.ThorPanel, 'numeric');
            app.QATab_Thor_SetSpeedNumeric.Limits = [-100 100];
            app.QATab_Thor_SetSpeedNumeric.RoundFractionalValues = 'on';
            app.QATab_Thor_SetSpeedNumeric.ValueChangedFcn = createCallbackFcn(app, @QATab_Thor_SetSpeedNumericValueChanged, true);
            app.QATab_Thor_SetFollowingDistanceNumeric = uieditfield(app.ThorPanel, 'numeric');
            app.QATab_Thor_SetFollowingDistanceNumeric.Limits = [0 255];
            app.QATab_Thor_SetFollowingDistanceNumeric.RoundFractionalValues = 'on';
            app.QATab_Thor_SetFollowingDistanceNumeric.ValueChangedFcn = createCallbackFcn(app, @QATab_Thor_SetFollowingDistanceNumericValueChanged, true);
            app.HulkPanel = uipanel(app.QuickAdjustTab);
            app.HulkPanel.Title = 'Hulk';
            app.SetSpeedcmsLabel_3 = uilabel(app.HulkPanel);
            app.SetSpeedcmsLabel_3.Text = 'Set Speed (cm/s)';
            app.QATab_Hulk_SetSpeedSlider = uislider(app.HulkPanel);
            app.QATab_Hulk_SetSpeedSlider.Limits = [-100 100];
            app.QATab_Hulk_SetSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @QATab_Hulk_SetSpeedSliderValueChanging, true);
            app.SetFollowingDistancecmLabel_3 = uilabel(app.HulkPanel);
            app.SetFollowingDistancecmLabel_3.Text = 'Set Following Distance (cm)';
            app.QATab_Hulk_SetFollowingDistanceSlider = uislider(app.HulkPanel);
            app.QATab_Hulk_SetFollowingDistanceSlider.Limits = [0 255];
            app.QATab_Hulk_SetFollowingDistanceSlider.MajorTicks = [0 64 128 192 255];
            app.QATab_Hulk_SetFollowingDistanceSlider.ValueChangingFcn = createCallbackFcn(app, @QATab_Hulk_SetFollowingDistanceSliderValueChanging, true);
            app.ConnectedLamp_3Label = uilabel(app.HulkPanel);
            app.ConnectedLamp_3Label.Text = 'Connected';
            app.QATab_Hulk_ConnectedLamp = uilamp(app.HulkPanel);
            app.QATab_Hulk_ConnectButton = uibutton(app.HulkPanel, 'state');
            app.QATab_Hulk_ConnectButton.ValueChangedFcn = createCallbackFcn(app, @QATab_Hulk_ConnectButtonValueChanged, true);
            app.QATab_Hulk_ConnectButton.Text = 'Connect';
            app.QATab_Hulk_SetSpeedNumeric = uieditfield(app.HulkPanel, 'numeric');
            app.QATab_Hulk_SetSpeedNumeric.Limits = [-100 100];
            app.QATab_Hulk_SetSpeedNumeric.RoundFractionalValues = 'on';
            app.QATab_Hulk_SetSpeedNumeric.ValueChangedFcn = createCallbackFcn(app, @QATab_Hulk_SetSpeedNumericValueChanged, true);
            app.QATab_Hulk_SetFollowingDistanceNumeric = uieditfield(app.HulkPanel, 'numeric');
            app.QATab_Hulk_SetFollowingDistanceNumeric.Limits = [0 255];
            app.QATab_Hulk_SetFollowingDistanceNumeric.RoundFractionalValues = 'on';
            app.QATab_Hulk_SetFollowingDistanceNumeric.ValueChangedFcn = createCallbackFcn(app, @QATab_Hulk_SetFollowingDistanceNumericValueChanged, true);
            app.CaptainAmericaPanel = uipanel(app.QuickAdjustTab);
            app.CaptainAmericaPanel.Title = 'Captain America';
            app.SetSpeedcmsLabel_4 = uilabel(app.CaptainAmericaPanel);
            app.SetSpeedcmsLabel_4.Text = 'Set Speed (cm/s)';
            app.QATab_CaptainAmerica_SetSpeedSlider = uislider(app.CaptainAmericaPanel);
            app.QATab_CaptainAmerica_SetSpeedSlider.Limits = [-100 100];
            app.QATab_CaptainAmerica_SetSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @QATab_CaptainAmerica_SetSpeedSliderValueChanging, true);
            app.SetFollowingDistancecmLabel_4 = uilabel(app.CaptainAmericaPanel);
            app.SetFollowingDistancecmLabel_4.Text = 'Set Following Distance (cm)';
            app.QATab_CaptainAmerica_SetFollowingDistanceSlider = uislider(app.CaptainAmericaPanel);
            app.QATab_CaptainAmerica_SetFollowingDistanceSlider.Limits = [0 255];
            app.QATab_CaptainAmerica_SetFollowingDistanceSlider.MajorTicks = [0 64 128 192 255];
            app.QATab_CaptainAmerica_SetFollowingDistanceSlider.ValueChangingFcn = createCallbackFcn(app, @QATab_CaptainAmerica_SetFollowingDistanceSliderValueChanging, true);
            app.ConnectedLamp_4Label = uilabel(app.CaptainAmericaPanel);
            app.ConnectedLamp_4Label.Text = 'Connected';
            app.QATab_CaptainAmerica_ConnectedLamp = uilamp(app.CaptainAmericaPanel);
            app.QATab_CaptainAmerica_ConnectButton = uibutton(app.CaptainAmericaPanel, 'state');
            app.QATab_CaptainAmerica_ConnectButton.ValueChangedFcn = createCallbackFcn(app, @QATab_CaptainAmerica_ConnectButtonValueChanged, true);
            app.QATab_CaptainAmerica_ConnectButton.Text = 'Connect';
            app.QATab_CaptainAmerica_SetSpeedNumeric = uieditfield(app.CaptainAmericaPanel, 'numeric');
            app.QATab_CaptainAmerica_SetSpeedNumeric.Limits = [-100 100];
            app.QATab_CaptainAmerica_SetSpeedNumeric.RoundFractionalValues = 'on';
            app.QATab_CaptainAmerica_SetSpeedNumeric.ValueChangedFcn = createCallbackFcn(app, @QATab_CaptainAmerica_SetSpeedNumericValueChanged, true);
            app.QATab_CaptainAmerica_SetFollowingDistanceNumeric = uieditfield(app.CaptainAmericaPanel, 'numeric');
            app.QATab_CaptainAmerica_SetFollowingDistanceNumeric.Limits = [0 255];
            app.QATab_CaptainAmerica_SetFollowingDistanceNumeric.RoundFractionalValues = 'on';
            app.QATab_CaptainAmerica_SetFollowingDistanceNumeric.ValueChangedFcn = createCallbackFcn(app, @QATab_CaptainAmerica_SetFollowingDistanceNumericValueChanged, true);
            app.IronManTab = uitab(app.TabGroup);
            app.IronManTab.Title = 'Iron Man';
            app.IronManTab_LeftLabel = uilabel(app.IronManTab);
            app.IronManTab_LeftLabel.Text = 'Left';
            app.IronManTab_MiddleLabel = uilabel(app.IronManTab);
            app.IronManTab_MiddleLabel.Text = 'Middle';
            app.IronManTab_RightLabel = uilabel(app.IronManTab);
            app.IronManTab_RightLabel.Text = 'Right';
            app.IronManTab_MiddleLamp = uilamp(app.IronManTab);
            app.IronManTab_LeftSensorVal = uilabel(app.IronManTab);
            app.IronManTab_LeftSensorVal.Text = '00';
            app.IronManTab_RightSensorVal = uilabel(app.IronManTab);
            app.IronManTab_RightSensorVal.Text = '00';
            app.SetSpeedSlider_5Label = uilabel(app.IronManTab);
            app.SetSpeedSlider_5Label.Text = 'Set Speed';
            app.IronManTab_SetSpeedSlider = uislider(app.IronManTab);
            app.IronManTab_SetSpeedSlider.Limits = [-100 100];
            app.IronManTab_SetSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @IronManTab_SetSpeedSliderValueChanging, true);
            app.SetFollowingDistanceSlider_5Label = uilabel(app.IronManTab);
            app.SetFollowingDistanceSlider_5Label.Text = 'Set Following Distance';
            app.IronManTab_SetFollowingDistanceSlider = uislider(app.IronManTab);
            app.IronManTab_SetFollowingDistanceSlider.Limits = [0 255];
            app.IronManTab_SetFollowingDistanceSlider.MajorTicks = [0 64 128 192 255];
            app.IronManTab_SetFollowingDistanceSlider.ValueChangingFcn = createCallbackFcn(app, @IronManTab_SetFollowingDistanceSliderValueChanging, true);
            app.ConnectedLampLabel_2 = uilabel(app.IronManTab);
            app.ConnectedLampLabel_2.Text = 'Connected';
            app.IronManTab_ConnectedLamp = uilamp(app.IronManTab);
            app.SpeedometerGauge_5Label = uilabel(app.IronManTab);
            app.SpeedometerGauge_5Label.Text = 'Speedometer';
            app.IronManTab_SpeedometerGauge = uigauge(app.IronManTab, 'semicircular');
            app.IronManTab_SpeedometerGauge.Limits = [-100 100];
            app.UltrasonicSensorGauge_5Label = uilabel(app.IronManTab);
            app.UltrasonicSensorGauge_5Label.Text = 'Ultrasonic Sensor';
            app.IronManTab_UltrasonicSensorGauge = uigauge(app.IronManTab, 'linear');
            app.IronManTab_UltrasonicSensorGauge.Limits = [0 255];
            app.IronManTab_UltrasonicSensorGauge.MajorTicks = [0 64 128 192 255];
            app.IronManTab_UltrasonicSensorGauge.MinorTicks = [0 16 32 48 64 80 96 112 128 144 160 176 192 208 224 240 255];
            app.DataloggingLampLabel = uilabel(app.IronManTab);
            app.DataloggingLampLabel.Text = 'Data logging';
            app.IronManTab_DataloggingLamp = uilamp(app.IronManTab);
            app.IronManTab_EvaluationPanel = uipanel(app.IronManTab);
            app.IronManTab_EvaluationPanel.Title = 'Evaluation';
            app.IronManTab_C1Label = uilabel(app.IronManTab_EvaluationPanel);
            app.IronManTab_C1Label.Text = 'C1:';
            app.IronManTab_C1Val = uilabel(app.IronManTab_EvaluationPanel);
            app.IronManTab_C1Val.Text = '00';
            app.IronManTab_C2Label = uilabel(app.IronManTab_EvaluationPanel);
            app.IronManTab_C2Label.Text = 'C2:';
            app.IronManTab_C2Val = uilabel(app.IronManTab_EvaluationPanel);
            app.IronManTab_C2Val.Text = '00';
            app.IronManTab_C3Label = uilabel(app.IronManTab_EvaluationPanel);
            app.IronManTab_C3Label.Text = 'C3:';
            app.IronManTab_C3Val = uilabel(app.IronManTab_EvaluationPanel);
            app.IronManTab_C3Val.Text = '00';
            app.IronManTab_BatteryVoltageLabel = uilabel(app.IronManTab);
            app.IronManTab_BatteryVoltageLabel.Text = 'Battery Voltage';
            app.IronManTab_BatteryVoltageValue = uilabel(app.IronManTab);
            app.IronManTab_BatteryVoltageValue.Text = '0.0V';
            app.IronManTab_CommandPanel = uipanel(app.IronManTab);
            app.IronManTab_CommandPanel.Title = 'Command';
            app.IronManTab_CommandbeingexecutedLabel = uilabel(app.IronManTab_CommandPanel);
            app.IronManTab_CommandbeingexecutedLabel.Text = 'Command being executed:';
            app.IronManTab_CommandBeingExecutedText = uilabel(app.IronManTab_CommandPanel);
            app.IronManTab_CommandBeingExecutedText.Text = 'Drive the outer loop, whole';
            app.IronMaTab_TabGroup = uitabgroup(app.IronManTab_CommandPanel);
            app.IronManTab_HighlevelTab = uitab(app.IronMaTab_TabGroup);
            app.IronManTab_HighlevelTab.Title = 'High-level';
            app.DriveLabel = uilabel(app.IronManTab_HighlevelTab);
            app.DriveLabel.Text = 'Drive:';
            app.IronManTab_DriveTask = uilistbox(app.IronManTab_HighlevelTab);
            app.IronManTab_DriveTask.Items = {'Outer loop - whole', 'Outer loop - left', 'Outer loop - right', 'Inner loop - whole', 'Inner loop - left', 'Inner loop - right', 'Random'};
            app.IronManTab_DriveTask.Value = 'Outer loop - whole';
            app.IronManTab_Task = uibuttongroup(app.IronManTab_HighlevelTab);
            app.IronManTab_Task.SelectionChangedFcn = createCallbackFcn(app, @IronManTab_TaskSelectionChanged, true);
            app.IronManTab_Task.Title = 'Task';
            app.IronManTab_DriveButton = uiradiobutton(app.IronManTab_Task);
            app.IronManTab_DriveButton.Text = 'Drive';
            app.IronManTab_DriveButton.Value = true;
            app.IronManTab_ParkButton = uiradiobutton(app.IronManTab_Task);
            app.IronManTab_ParkButton.Text = 'Park';
            app.IronManTab_UpdateHighCommandButton = uibutton(app.IronManTab_HighlevelTab, 'push');
            app.IronManTab_UpdateHighCommandButton.ButtonPushedFcn = createCallbackFcn(app, @IronManTab_UpdateHighCommandButtonPushed, true);
            app.IronManTab_UpdateHighCommandButton.Text = 'Send high-level command';
            app.IronManTab_LowlevelTab = uitab(app.IronMaTab_TabGroup);
            app.IronManTab_LowlevelTab.Title = 'Low-level';
            app.StopdistanceEditFieldLabel = uilabel(app.IronManTab_LowlevelTab);
            app.StopdistanceEditFieldLabel.Text = 'Stop distance';
            app.IronManTab_StopDistanceField = uieditfield(app.IronManTab_LowlevelTab, 'numeric');
            app.StopdegreesEditFieldLabel = uilabel(app.IronManTab_LowlevelTab);
            app.StopdegreesEditFieldLabel.Text = 'Stop degrees';
            app.IronManTab_StopDegreesField = uieditfield(app.IronManTab_LowlevelTab, 'numeric');
            app.IronManTab_Navigation = uibuttongroup(app.IronManTab_LowlevelTab);
            app.IronManTab_Navigation.Title = 'Navigation';
            app.IronManTab_LinefollowingButton = uiradiobutton(app.IronManTab_Navigation);
            app.IronManTab_LinefollowingButton.Text = 'Line following';
            app.IronManTab_LinefollowingButton.Value = true;
            app.IronManTab_DeadreckoningButton = uiradiobutton(app.IronManTab_Navigation);
            app.IronManTab_DeadreckoningButton.Text = 'Dead reckoning';
            app.IronManTab_StopCondition = uibuttongroup(app.IronManTab_LowlevelTab);
            app.IronManTab_StopCondition.Title = 'Stop condition';
            app.IronManTab_NoneButton = uiradiobutton(app.IronManTab_StopCondition);
            app.IronManTab_NoneButton.Text = 'None';
            app.IronManTab_NoneButton.Value = true;
            app.IronManTab_DistanceButton = uiradiobutton(app.IronManTab_StopCondition);
            app.IronManTab_DistanceButton.Text = 'Distance';
            app.IronManTab_DegreesButton = uiradiobutton(app.IronManTab_StopCondition);
            app.IronManTab_DegreesButton.Text = 'Degrees';
            app.IronManTab_BluetapeButton = uiradiobutton(app.IronManTab_StopCondition);
            app.IronManTab_BluetapeButton.Text = 'Blue tape';
            app.IronManTab_RedtapeButton = uiradiobutton(app.IronManTab_StopCondition);
            app.IronManTab_RedtapeButton.Text = 'Red tape';
            app.IronManTab_ErrorSignal = uibuttongroup(app.IronManTab_LowlevelTab);
            app.IronManTab_ErrorSignal.Title = 'Error signal';
            app.IronManTab_LeftsensorButton = uiradiobutton(app.IronManTab_ErrorSignal);
            app.IronManTab_LeftsensorButton.Text = 'Left sensor';
            app.IronManTab_LeftsensorButton.Value = true;
            app.IronManTab_BothsensorsButton = uiradiobutton(app.IronManTab_ErrorSignal);
            app.IronManTab_BothsensorsButton.Text = 'Both sensors';
            app.IronManTab_RightsensorButton = uiradiobutton(app.IronManTab_ErrorSignal);
            app.IronManTab_RightsensorButton.Text = 'Right sensor';
            app.IronManTab_UpdateLowCommandButton = uibutton(app.IronManTab_LowlevelTab, 'push');
            app.IronManTab_UpdateLowCommandButton.ButtonPushedFcn = createCallbackFcn(app, @IronManTab_UpdateLowCommandButtonPushed, true);
            app.IronManTab_UpdateLowCommandButton.Text = 'Send low-level command';
            app.IronManTab_ConnectButton = uibutton(app.IronManTab, 'state');
            app.IronManTab_ConnectButton.ValueChangedFcn = createCallbackFcn(app, @IronManTab_ConnectButtonValueChanged, true);
            app.IronManTab_ConnectButton.Text = 'Connect';
            app.IronManTab_DataLogButton = uibutton(app.IronManTab, 'state');
            app.IronManTab_DataLogButton.ValueChangedFcn = createCallbackFcn(app, @IronManTab_DataLogButtonValueChanged, true);
            app.IronManTab_DataLogButton.Text = 'Data log';
            app.ThorTab = uitab(app.TabGroup);
            app.ThorTab.Title = 'Thor';
            app.ThorTab_LeftLabel = uilabel(app.ThorTab);
            app.ThorTab_LeftLabel.Text = 'Left';
            app.ThorTab_MiddleLabel = uilabel(app.ThorTab);
            app.ThorTab_MiddleLabel.Text = 'Middle';
            app.ThorTab_RightLabel = uilabel(app.ThorTab);
            app.ThorTab_RightLabel.Text = 'Right';
            app.ThorTab_MiddleLamp = uilamp(app.ThorTab);
            app.ThorTab_LeftSensorVal = uilabel(app.ThorTab);
            app.ThorTab_LeftSensorVal.Text = '00';
            app.ThorTab_RightSensorVal = uilabel(app.ThorTab);
            app.ThorTab_RightSensorVal.Text = '00';
            app.ThorTab_EvaluationPanel = uipanel(app.ThorTab);
            app.ThorTab_EvaluationPanel.Title = 'Evaluation';
            app.ThorTab_C1Label = uilabel(app.ThorTab_EvaluationPanel);
            app.ThorTab_C1Label.Text = 'C1:';
            app.ThorTab_C1Val = uilabel(app.ThorTab_EvaluationPanel);
            app.ThorTab_C1Val.Text = '00';
            app.ThorTab_C2Label = uilabel(app.ThorTab_EvaluationPanel);
            app.ThorTab_C2Label.Text = 'C2:';
            app.ThorTab_C2Val = uilabel(app.ThorTab_EvaluationPanel);
            app.ThorTab_C2Val.Text = '00';
            app.ThorTab_C3Label = uilabel(app.ThorTab_EvaluationPanel);
            app.ThorTab_C3Label.Text = 'C3:';
            app.ThorTab_C3Val = uilabel(app.ThorTab_EvaluationPanel);
            app.ThorTab_C3Val.Text = '00';
            app.ThorTab_BatteryVoltageLabel = uilabel(app.ThorTab);
            app.ThorTab_BatteryVoltageLabel.Text = 'Battery Voltage';
            app.ThorTab_BatteryVoltageValue = uilabel(app.ThorTab);
            app.ThorTab_BatteryVoltageValue.Text = '0.0V';
            app.SetSpeedSlider_7Label = uilabel(app.ThorTab);
            app.SetSpeedSlider_7Label.Text = 'Set Speed';
            app.ThorTab_SetSpeedSlider = uislider(app.ThorTab);
            app.ThorTab_SetSpeedSlider.Limits = [-100 100];
            app.ThorTab_SetSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @ThorTab_SetSpeedSliderValueChanging, true);
            app.SetFollowingDistanceSlider_7Label = uilabel(app.ThorTab);
            app.SetFollowingDistanceSlider_7Label.Text = 'Set Following Distance';
            app.ThorTab_SetFollowingDistanceSlider = uislider(app.ThorTab);
            app.ThorTab_SetFollowingDistanceSlider.Limits = [0 255];
            app.ThorTab_SetFollowingDistanceSlider.MajorTicks = [0 64 128 192 255];
            app.ThorTab_SetFollowingDistanceSlider.ValueChangingFcn = createCallbackFcn(app, @ThorTab_SetFollowingDistanceSliderValueChanging, true);
            app.ConnectedLamp_2Label_2 = uilabel(app.ThorTab);
            app.ConnectedLamp_2Label_2.Text = 'Connected';
            app.ThorTab_ConnectedLamp = uilamp(app.ThorTab);
            app.SpeedometerGauge_8Label = uilabel(app.ThorTab);
            app.SpeedometerGauge_8Label.Text = 'Speedometer';
            app.ThorTab_SpeedometerGauge = uigauge(app.ThorTab, 'semicircular');
            app.ThorTab_SpeedometerGauge.Limits = [-100 100];
            app.UltrasonicSensorGauge_8Label = uilabel(app.ThorTab);
            app.UltrasonicSensorGauge_8Label.Text = 'Ultrasonic Sensor';
            app.ThorTab_UltrasonicSensorGauge = uigauge(app.ThorTab, 'linear');
            app.ThorTab_UltrasonicSensorGauge.Limits = [0 255];
            app.ThorTab_UltrasonicSensorGauge.MajorTicks = [0 64 128 192 255];
            app.DataloggingLamp_2Label = uilabel(app.ThorTab);
            app.DataloggingLamp_2Label.Text = 'Data logging';
            app.ThorTab_DataloggingLamp = uilamp(app.ThorTab);
            app.ThorTab_ConnectButton = uibutton(app.ThorTab, 'state');
            app.ThorTab_ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ThorTab_ConnectButtonValueChanged, true);
            app.ThorTab_ConnectButton.Text = 'Connect';
            app.ThorTab_DataLogButton = uibutton(app.ThorTab, 'state');
            app.ThorTab_DataLogButton.ValueChangedFcn = createCallbackFcn(app, @ThorTab_DataLogButtonValueChanged, true);
            app.ThorTab_DataLogButton.Text = 'Data log';
            app.ThorTab_CommandPanel = uipanel(app.ThorTab);
            app.ThorTab_CommandPanel.Title = 'Command';
            app.ThorTab_CommandbeingexecutedLabel = uilabel(app.ThorTab_CommandPanel);
            app.ThorTab_CommandbeingexecutedLabel.Text = 'Command being executed:';
            app.ThorTab_CommandBeingExecutedText = uilabel(app.ThorTab_CommandPanel);
            app.ThorTab_CommandBeingExecutedText.Text = 'Drive the outer loop, whole';
            app.ThorTab_TabGroup = uitabgroup(app.ThorTab_CommandPanel);
            app.ThorTab_HighlevelTab = uitab(app.ThorTab_TabGroup);
            app.ThorTab_HighlevelTab.Title = 'High-level';
            app.DriveLabel_2 = uilabel(app.ThorTab_HighlevelTab);
            app.DriveLabel_2.Text = 'Drive:';
            app.ThorTab_DriveTask = uilistbox(app.ThorTab_HighlevelTab);
            app.ThorTab_DriveTask.Items = {'Outer loop - whole', 'Outer loop - left', 'Outer loop - right', 'Inner loop - whole', 'Inner loop - left', 'Inner loop - right', 'Random'};
            app.ThorTab_DriveTask.Value = 'Outer loop - whole';
            app.ThorTab_UpdateHighCommandButton = uibutton(app.ThorTab_HighlevelTab, 'push');
            app.ThorTab_UpdateHighCommandButton.ButtonPushedFcn = createCallbackFcn(app, @ThorTab_UpdateHighCommandButtonPushed, true);
            app.ThorTab_UpdateHighCommandButton.Text = 'Send high-level command';
            app.ThorTab_Task = uibuttongroup(app.ThorTab_HighlevelTab);
            app.ThorTab_Task.SelectionChangedFcn = createCallbackFcn(app, @ThorTab_TaskSelectionChanged, true);
            app.ThorTab_Task.Title = 'Task';
            app.ThorTab_DriveButton = uiradiobutton(app.ThorTab_Task);
            app.ThorTab_DriveButton.Text = 'Drive';
            app.ThorTab_DriveButton.Value = true;
            app.ThorTab_ParkButton = uiradiobutton(app.ThorTab_Task);
            app.ThorTab_ParkButton.Text = 'Park';
            app.ThorTab_LowlevelTab = uitab(app.ThorTab_TabGroup);
            app.ThorTab_LowlevelTab.Title = 'Low-level';
            app.StopdistanceEditFieldLabel_2 = uilabel(app.ThorTab_LowlevelTab);
            app.StopdistanceEditFieldLabel_2.Text = 'Stop distance';
            app.ThorTab_StopDistanceField = uieditfield(app.ThorTab_LowlevelTab, 'numeric');
            app.StopdegreesEditFieldLabel_2 = uilabel(app.ThorTab_LowlevelTab);
            app.StopdegreesEditFieldLabel_2.Text = 'Stop degrees';
            app.ThorTab_StopDegreesField = uieditfield(app.ThorTab_LowlevelTab, 'numeric');
            app.ThorTab_Navigation = uibuttongroup(app.ThorTab_LowlevelTab);
            app.ThorTab_Navigation.Title = 'Navigation';
            app.ThorTab_LinefollowingButton = uiradiobutton(app.ThorTab_Navigation);
            app.ThorTab_LinefollowingButton.Text = 'Line following';
            app.ThorTab_LinefollowingButton.Value = true;
            app.ThorTab_DeadreckoningButton = uiradiobutton(app.ThorTab_Navigation);
            app.ThorTab_DeadreckoningButton.Text = 'Dead reckoning';
            app.ThorTab_StopCondition = uibuttongroup(app.ThorTab_LowlevelTab);
            app.ThorTab_StopCondition.Title = 'Stop condition';
            app.ThorTab_NoneButton = uiradiobutton(app.ThorTab_StopCondition);
            app.ThorTab_NoneButton.Text = 'None';
            app.ThorTab_NoneButton.Value = true;
            app.ThorTab_DistanceButton = uiradiobutton(app.ThorTab_StopCondition);
            app.ThorTab_DistanceButton.Text = 'Distance';
            app.ThorTab_DegreesButton = uiradiobutton(app.ThorTab_StopCondition);
            app.ThorTab_DegreesButton.Text = 'Degrees';
            app.ThorTab_BluetapeButton = uiradiobutton(app.ThorTab_StopCondition);
            app.ThorTab_BluetapeButton.Text = 'Blue tape';
            app.ThorTab_RedtapeButton = uiradiobutton(app.ThorTab_StopCondition);
            app.ThorTab_RedtapeButton.Text = 'Red tape';
            app.ThorTab_ErrorSignal = uibuttongroup(app.ThorTab_LowlevelTab);
            app.ThorTab_ErrorSignal.Title = 'Error signal';
            app.ThorTab_LeftsensorButton = uiradiobutton(app.ThorTab_ErrorSignal);
            app.ThorTab_LeftsensorButton.Text = 'Left sensor';
            app.ThorTab_LeftsensorButton.Value = true;
            app.ThorTab_BothsensorsButton = uiradiobutton(app.ThorTab_ErrorSignal);
            app.ThorTab_BothsensorsButton.Text = 'Both sensors';
            app.ThorTab_RightsensorButton = uiradiobutton(app.ThorTab_ErrorSignal);
            app.ThorTab_RightsensorButton.Text = 'Right sensor';
            app.ThorTab_UpdateLowCommandButton = uibutton(app.ThorTab_LowlevelTab, 'push');
            app.ThorTab_UpdateLowCommandButton.ButtonPushedFcn = createCallbackFcn(app, @ThorTab_UpdateLowCommandButtonPushed, true);
            app.ThorTab_UpdateLowCommandButton.Text = 'Send low-level command';
            app.HulkTab = uitab(app.TabGroup);
            app.HulkTab.Title = 'Hulk';
            app.HulkTab_LeftLabel = uilabel(app.HulkTab);
            app.HulkTab_LeftLabel.Text = 'Left';
            app.HulkTab_MiddleLabel = uilabel(app.HulkTab);
            app.HulkTab_MiddleLabel.Text = 'Middle';
            app.HulkTab_RightLabel = uilabel(app.HulkTab);
            app.HulkTab_RightLabel.Text = 'Right';
            app.HulkTab_MiddleLamp = uilamp(app.HulkTab);
            app.HulkTab_LeftSensorVal = uilabel(app.HulkTab);
            app.HulkTab_LeftSensorVal.Text = '00';
            app.HulkTab_RightSensorVal = uilabel(app.HulkTab);
            app.HulkTab_RightSensorVal.Text = '00';
            app.HulkTab_EvaluationPanel = uipanel(app.HulkTab);
            app.HulkTab_EvaluationPanel.Title = 'Evaluation';
            app.HulkTab_C1Label = uilabel(app.HulkTab_EvaluationPanel);
            app.HulkTab_C1Label.Text = 'C1:';
            app.HulkTab_C1Val = uilabel(app.HulkTab_EvaluationPanel);
            app.HulkTab_C1Val.Text = '00';
            app.HulkTab_C2Label = uilabel(app.HulkTab_EvaluationPanel);
            app.HulkTab_C2Label.Text = 'C2:';
            app.HulkTab_C2Val = uilabel(app.HulkTab_EvaluationPanel);
            app.HulkTab_C2Val.Text = '00';
            app.HulkTab_C3Label = uilabel(app.HulkTab_EvaluationPanel);
            app.HulkTab_C3Label.Text = 'C3:';
            app.HulkTab_C3Val = uilabel(app.HulkTab_EvaluationPanel);
            app.HulkTab_C3Val.Text = '00';
            app.HulkTab_BatteryVoltageLabel = uilabel(app.HulkTab);
            app.HulkTab_BatteryVoltageLabel.Text = 'Battery Voltage';
            app.HulkTab_BatteryVoltageValue = uilabel(app.HulkTab);
            app.HulkTab_BatteryVoltageValue.Text = '0.00V (or picture)';
            app.SetSpeedSlider_9Label = uilabel(app.HulkTab);
            app.SetSpeedSlider_9Label.Text = 'Set Speed';
            app.HulkTab_SetSpeedSlider = uislider(app.HulkTab);
            app.HulkTab_SetSpeedSlider.Limits = [-100 100];
            app.HulkTab_SetSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @HulkTab_SetSpeedSliderValueChanging, true);
            app.SetFollowingDistanceSlider_9Label = uilabel(app.HulkTab);
            app.SetFollowingDistanceSlider_9Label.Text = 'Set Following Distance';
            app.HulkTab_SetFollowingDistanceSlider = uislider(app.HulkTab);
            app.HulkTab_SetFollowingDistanceSlider.Limits = [0 255];
            app.HulkTab_SetFollowingDistanceSlider.MajorTicks = [0 64 128 192 255];
            app.HulkTab_SetFollowingDistanceSlider.ValueChangingFcn = createCallbackFcn(app, @HulkTab_SetFollowingDistanceSliderValueChanging, true);
            app.ConnectedLamp_4Label_2 = uilabel(app.HulkTab);
            app.ConnectedLamp_4Label_2.Text = 'Connected';
            app.HulkTab_ConnectedLamp = uilamp(app.HulkTab);
            app.SpeedometerGauge_9Label = uilabel(app.HulkTab);
            app.SpeedometerGauge_9Label.Text = 'Speedometer';
            app.HulkTab_SpeedometerGauge = uigauge(app.HulkTab, 'semicircular');
            app.HulkTab_SpeedometerGauge.Limits = [-100 100];
            app.UltrasonicSensorGauge_9Label = uilabel(app.HulkTab);
            app.UltrasonicSensorGauge_9Label.Text = 'Ultrasonic Sensor';
            app.HulkTab_UltrasonicSensorGauge = uigauge(app.HulkTab, 'linear');
            app.HulkTab_UltrasonicSensorGauge.Limits = [0 255];
            app.HulkTab_UltrasonicSensorGauge.MajorTicks = [0 64 128 192 255];
            app.DataloggingLamp_3Label = uilabel(app.HulkTab);
            app.DataloggingLamp_3Label.Text = 'Data logging';
            app.HulkTab_DataloggingLamp = uilamp(app.HulkTab);
            app.HulkTab_ConnectButton = uibutton(app.HulkTab, 'state');
            app.HulkTab_ConnectButton.ValueChangedFcn = createCallbackFcn(app, @HulkTab_ConnectButtonValueChanged, true);
            app.HulkTab_ConnectButton.Text = 'Connect';
            app.HulkTab_DataLogButton = uibutton(app.HulkTab, 'state');
            app.HulkTab_DataLogButton.ValueChangedFcn = createCallbackFcn(app, @HulkTab_DataLogButtonValueChanged, true);
            app.HulkTab_DataLogButton.Text = 'Data log';
            app.HulkTab_CommandPanel = uipanel(app.HulkTab);
            app.HulkTab_CommandPanel.Title = 'Command';
            app.HulkTab_CommandbeingexecutedLabel = uilabel(app.HulkTab_CommandPanel);
            app.HulkTab_CommandbeingexecutedLabel.Text = 'Command being executed:';
            app.HulkTab_CommandBeingExecutedText = uilabel(app.HulkTab_CommandPanel);
            app.HulkTab_CommandBeingExecutedText.Text = 'Drive the outer loop, whole';
            app.HulkTab_TabGroup = uitabgroup(app.HulkTab_CommandPanel);
            app.HulkTab_HighlevelTab = uitab(app.HulkTab_TabGroup);
            app.HulkTab_HighlevelTab.Title = 'High-level';
            app.DriveLabel_3 = uilabel(app.HulkTab_HighlevelTab);
            app.DriveLabel_3.Text = 'Drive:';
            app.HulkTab_DriveTask = uilistbox(app.HulkTab_HighlevelTab);
            app.HulkTab_DriveTask.Items = {'Outer loop - whole', 'Outer loop - left', 'Outer loop - right', 'Inner loop - whole', 'Inner loop - left', 'Inner loop - right', 'Random'};
            app.HulkTab_DriveTask.Value = 'Outer loop - whole';
            app.HulkTab_UpdateHighCommandButton = uibutton(app.HulkTab_HighlevelTab, 'push');
            app.HulkTab_UpdateHighCommandButton.ButtonPushedFcn = createCallbackFcn(app, @HulkTab_UpdateHighCommandButtonPushed, true);
            app.HulkTab_UpdateHighCommandButton.Text = 'Send high-level command';
            app.HulkTab_Task = uibuttongroup(app.HulkTab_HighlevelTab);
            app.HulkTab_Task.SelectionChangedFcn = createCallbackFcn(app, @HulkTab_TaskSelectionChanged, true);
            app.HulkTab_Task.Title = 'Task';
            app.HulkTab_DriveButton = uiradiobutton(app.HulkTab_Task);
            app.HulkTab_DriveButton.Text = 'Drive';
            app.HulkTab_DriveButton.Value = true;
            app.HulkTab_ParkButton = uiradiobutton(app.HulkTab_Task);
            app.HulkTab_ParkButton.Text = 'Park';
            app.HulkTab_LowlevelTab = uitab(app.HulkTab_TabGroup);
            app.HulkTab_LowlevelTab.Title = 'Low-level';
            app.StopdistanceEditFieldLabel_3 = uilabel(app.HulkTab_LowlevelTab);
            app.StopdistanceEditFieldLabel_3.Text = 'Stop distance';
            app.HulkTab_StopDistanceField = uieditfield(app.HulkTab_LowlevelTab, 'numeric');
            app.StopdegreesEditFieldLabel_3 = uilabel(app.HulkTab_LowlevelTab);
            app.StopdegreesEditFieldLabel_3.Text = 'Stop degrees';
            app.HulkTab_StopDegreesField = uieditfield(app.HulkTab_LowlevelTab, 'numeric');
            app.HulkTab_Navigation = uibuttongroup(app.HulkTab_LowlevelTab);
            app.HulkTab_Navigation.Title = 'Navigation';
            app.HulkTab_LinefollowingButton = uiradiobutton(app.HulkTab_Navigation);
            app.HulkTab_LinefollowingButton.Text = 'Line following';
            app.HulkTab_LinefollowingButton.Value = true;
            app.HulkTab_DeadreckoningButton = uiradiobutton(app.HulkTab_Navigation);
            app.HulkTab_DeadreckoningButton.Text = 'Dead reckoning';
            app.HulkTab_StopCondition = uibuttongroup(app.HulkTab_LowlevelTab);
            app.HulkTab_StopCondition.Title = 'Stop condition';
            app.HulkTab_NoneButton = uiradiobutton(app.HulkTab_StopCondition);
            app.HulkTab_NoneButton.Text = 'None';
            app.HulkTab_NoneButton.Value = true;
            app.HulkTab_DistanceButton = uiradiobutton(app.HulkTab_StopCondition);
            app.HulkTab_DistanceButton.Text = 'Distance';
            app.HulkTab_DegreesButton = uiradiobutton(app.HulkTab_StopCondition);
            app.HulkTab_DegreesButton.Text = 'Degrees';
            app.HulkTab_BluetapeButton = uiradiobutton(app.HulkTab_StopCondition);
            app.HulkTab_BluetapeButton.Text = 'Blue tape';
            app.HulkTab_RedtapeButton = uiradiobutton(app.HulkTab_StopCondition);
            app.HulkTab_RedtapeButton.Text = 'Red tape';
            app.HulkTab_ErrorSignal = uibuttongroup(app.HulkTab_LowlevelTab);
            app.HulkTab_ErrorSignal.Title = 'Error signal';
            app.HulkTab_LeftsensorButton = uiradiobutton(app.HulkTab_ErrorSignal);
            app.HulkTab_LeftsensorButton.Text = 'Left sensor';
            app.HulkTab_LeftsensorButton.Value = true;
            app.HulkTab_BothsensorsButton = uiradiobutton(app.HulkTab_ErrorSignal);
            app.HulkTab_BothsensorsButton.Text = 'Both sensors';
            app.HulkTab_RightsensorButton = uiradiobutton(app.HulkTab_ErrorSignal);
            app.HulkTab_RightsensorButton.Text = 'Right sensor';
            app.HulkTab_UpdateLowCommandButton = uibutton(app.HulkTab_LowlevelTab, 'push');
            app.HulkTab_UpdateLowCommandButton.ButtonPushedFcn = createCallbackFcn(app, @HulkTab_UpdateLowCommandButtonPushed, true);
            app.HulkTab_UpdateLowCommandButton.Text = 'Send low-level command';
            app.CaptainAmericaTab = uitab(app.TabGroup);
            app.CaptainAmericaTab.Title = 'Captain America';
            app.CaptainAmericaTab_LeftLabel = uilabel(app.CaptainAmericaTab);
            app.CaptainAmericaTab_LeftLabel.Text = 'Left';
            app.CaptainAmericaTab_MiddleLabel = uilabel(app.CaptainAmericaTab);
            app.CaptainAmericaTab_MiddleLabel.Text = 'Middle';
            app.CaptainAmericaTab_RightLabel = uilabel(app.CaptainAmericaTab);
            app.CaptainAmericaTab_RightLabel.Text = 'Right';
            app.CaptainAmericaTab_MiddleLamp = uilamp(app.CaptainAmericaTab);
            app.CaptainAmericaTab_LeftSensorVal = uilabel(app.CaptainAmericaTab);
            app.CaptainAmericaTab_LeftSensorVal.Text = '00';
            app.CaptainAmericaTab_RightSensorVal = uilabel(app.CaptainAmericaTab);
            app.CaptainAmericaTab_RightSensorVal.Text = '00';
            app.CaptainAmericaTab_EvaluationPanel = uipanel(app.CaptainAmericaTab);
            app.CaptainAmericaTab_EvaluationPanel.Title = 'Evaluation';
            app.CaptainAmericaTab_C1Label = uilabel(app.CaptainAmericaTab_EvaluationPanel);
            app.CaptainAmericaTab_C1Label.Text = 'C1:';
            app.CaptainAmericaTab_C1Val = uilabel(app.CaptainAmericaTab_EvaluationPanel);
            app.CaptainAmericaTab_C1Val.Text = '00';
            app.CaptainAmericaTab_C2Label = uilabel(app.CaptainAmericaTab_EvaluationPanel);
            app.CaptainAmericaTab_C2Label.Text = 'C2:';
            app.CaptainAmericaTab_C2Val = uilabel(app.CaptainAmericaTab_EvaluationPanel);
            app.CaptainAmericaTab_C2Val.Text = '00';
            app.CaptainAmericaTab_C3Label = uilabel(app.CaptainAmericaTab_EvaluationPanel);
            app.CaptainAmericaTab_C3Label.Text = 'C3:';
            app.CaptainAmericaTab_C3Val = uilabel(app.CaptainAmericaTab_EvaluationPanel);
            app.CaptainAmericaTab_C3Val.Text = '00';
            app.CaptainAmericaTab_BatteryVoltageLabel = uilabel(app.CaptainAmericaTab);
            app.CaptainAmericaTab_BatteryVoltageLabel.Text = 'Battery Voltage';
            app.CaptainAmericaTab_BatteryVoltageValue = uilabel(app.CaptainAmericaTab);
            app.CaptainAmericaTab_BatteryVoltageValue.Text = '0.00V (or picture)';
            app.SetSpeedSlider_11Label = uilabel(app.CaptainAmericaTab);
            app.SetSpeedSlider_11Label.Text = 'Set Speed';
            app.CaptainAmericaTab_SetSpeedSlider = uislider(app.CaptainAmericaTab);
            app.CaptainAmericaTab_SetSpeedSlider.Limits = [-100 100];
            app.CaptainAmericaTab_SetSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @CaptainAmericaTab_SetSpeedSliderValueChanging, true);
            app.SetFollowingDistanceSlider_11Label = uilabel(app.CaptainAmericaTab);
            app.SetFollowingDistanceSlider_11Label.Text = 'Set Following Distance';
            app.CaptainAmericaTab_SetFollowingDistanceSlider = uislider(app.CaptainAmericaTab);
            app.CaptainAmericaTab_SetFollowingDistanceSlider.Limits = [0 255];
            app.CaptainAmericaTab_SetFollowingDistanceSlider.MajorTicks = [0 64 128 192 255];
            app.CaptainAmericaTab_SetFollowingDistanceSlider.ValueChangingFcn = createCallbackFcn(app, @CaptainAmericaTab_SetFollowingDistanceSliderValueChanging, true);
            app.ConnectedLamp_5Label = uilabel(app.CaptainAmericaTab);
            app.ConnectedLamp_5Label.Text = 'Connected';
            app.CaptainAmericaTab_ConnectedLamp = uilamp(app.CaptainAmericaTab);
            app.SpeedometerGauge_10Label = uilabel(app.CaptainAmericaTab);
            app.SpeedometerGauge_10Label.Text = 'Speedometer';
            app.CaptainAmericaTab_SpeedometerGauge = uigauge(app.CaptainAmericaTab, 'semicircular');
            app.CaptainAmericaTab_SpeedometerGauge.Limits = [-100 100];
            app.UltrasonicSensorGauge_10Label = uilabel(app.CaptainAmericaTab);
            app.UltrasonicSensorGauge_10Label.Text = 'Ultrasonic Sensor';
            app.CaptainAmericaTab_UltrasonicSensorGauge = uigauge(app.CaptainAmericaTab, 'linear');
            app.CaptainAmericaTab_UltrasonicSensorGauge.Limits = [0 255];
            app.CaptainAmericaTab_UltrasonicSensorGauge.MajorTicks = [0 64 128 192 255];
            app.DataloggingLamp_4Label = uilabel(app.CaptainAmericaTab);
            app.DataloggingLamp_4Label.Text = 'Data logging';
            app.CaptainAmericaTab_DataloggingLamp = uilamp(app.CaptainAmericaTab);
            app.CaptainAmericaTab_ConnectButton = uibutton(app.CaptainAmericaTab, 'state');
            app.CaptainAmericaTab_ConnectButton.ValueChangedFcn = createCallbackFcn(app, @CaptainAmericaTab_ConnectButtonValueChanged, true);
            app.CaptainAmericaTab_ConnectButton.Text = 'Connect';
            app.CaptainAmericaTab_DataLogButton = uibutton(app.CaptainAmericaTab, 'state');
            app.CaptainAmericaTab_DataLogButton.ValueChangedFcn = createCallbackFcn(app, @CaptainAmericaTab_DataLogButtonValueChanged, true);
            app.CaptainAmericaTab_DataLogButton.Text = 'Data log';
            app.CaptainAmericaTab_CommandPanel = uipanel(app.CaptainAmericaTab);
            app.CaptainAmericaTab_CommandPanel.Title = 'Command';
            app.CaptainAmericaTab_CommandbeingexecutedLabel = uilabel(app.CaptainAmericaTab_CommandPanel);
            app.CaptainAmericaTab_CommandbeingexecutedLabel.Text = 'Command being executed:';
            app.CaptainAmericaTab_CommandBeingExecutedText = uilabel(app.CaptainAmericaTab_CommandPanel);
            app.CaptainAmericaTab_CommandBeingExecutedText.Text = 'Drive the outer loop, whole';
            app.CaptainAmericaTab_TabGroup = uitabgroup(app.CaptainAmericaTab_CommandPanel);
            app.CaptainAmericaTab_HighlevelTab = uitab(app.CaptainAmericaTab_TabGroup);
            app.CaptainAmericaTab_HighlevelTab.Title = 'High-level';
            app.DriveLabel_4 = uilabel(app.CaptainAmericaTab_HighlevelTab);
            app.DriveLabel_4.Text = 'Drive:';
            app.CaptainAmericaTab_DriveTask = uilistbox(app.CaptainAmericaTab_HighlevelTab);
            app.CaptainAmericaTab_DriveTask.Items = {'Outer loop - whole', 'Outer loop - left', 'Outer loop - right', 'Inner loop - whole', 'Inner loop - left', 'Inner loop - right', 'Random'};
            app.CaptainAmericaTab_DriveTask.Value = 'Outer loop - whole';
            app.CaptainAmericaTab_UpdateHighCommandButton = uibutton(app.CaptainAmericaTab_HighlevelTab, 'push');
            app.CaptainAmericaTab_UpdateHighCommandButton.ButtonPushedFcn = createCallbackFcn(app, @CaptainAmericaTab_UpdateHighCommandButtonPushed, true);
            app.CaptainAmericaTab_UpdateHighCommandButton.Text = 'Send high-level command';
            app.CaptainAmericaTab_Task = uibuttongroup(app.CaptainAmericaTab_HighlevelTab);
            app.CaptainAmericaTab_Task.SelectionChangedFcn = createCallbackFcn(app, @CaptainAmericaTab_TaskSelectionChanged, true);
            app.CaptainAmericaTab_Task.Title = 'Task';
            app.CaptainAmericaTab_DriveButton = uiradiobutton(app.CaptainAmericaTab_Task);
            app.CaptainAmericaTab_DriveButton.Text = 'Drive';
            app.CaptainAmericaTab_DriveButton.Value = true;
            app.CaptainAmericaTab_ParkButton = uiradiobutton(app.CaptainAmericaTab_Task);
            app.CaptainAmericaTab_ParkButton.Text = 'Park';
            app.CaptainAmericaTab_LowlevelTab = uitab(app.CaptainAmericaTab_TabGroup);
            app.CaptainAmericaTab_LowlevelTab.Title = 'Low-level';
            app.StopdistanceEditFieldLabel_4 = uilabel(app.CaptainAmericaTab_LowlevelTab);
            app.StopdistanceEditFieldLabel_4.Text = 'Stop distance';
            app.CaptainAmericaTab_StopDistanceField = uieditfield(app.CaptainAmericaTab_LowlevelTab, 'numeric');
            app.StopdegreesEditFieldLabel_4 = uilabel(app.CaptainAmericaTab_LowlevelTab);
            app.StopdegreesEditFieldLabel_4.Text = 'Stop degrees';
            app.CaptainAmericaTab_StopDegreesField = uieditfield(app.CaptainAmericaTab_LowlevelTab, 'numeric');
            app.CaptainAmericaTab_Navigation = uibuttongroup(app.CaptainAmericaTab_LowlevelTab);
            app.CaptainAmericaTab_Navigation.Title = 'Navigation';
            app.CaptainAmericaTab_LinefollowingButton = uiradiobutton(app.CaptainAmericaTab_Navigation);
            app.CaptainAmericaTab_LinefollowingButton.Text = 'Line following';
            app.CaptainAmericaTab_LinefollowingButton.Value = true;
            app.CaptainAmericaTab_DeadreckoningButton = uiradiobutton(app.CaptainAmericaTab_Navigation);
            app.CaptainAmericaTab_DeadreckoningButton.Text = 'Dead reckoning';
            app.CaptainAmericaTab_StopCondition = uibuttongroup(app.CaptainAmericaTab_LowlevelTab);
            app.CaptainAmericaTab_StopCondition.Title = 'Stop condition';
            app.CaptainAmericaTab_NoneButton = uiradiobutton(app.CaptainAmericaTab_StopCondition);
            app.CaptainAmericaTab_NoneButton.Text = 'None';
            app.CaptainAmericaTab_NoneButton.Value = true;
            app.CaptainAmericaTab_DistanceButton = uiradiobutton(app.CaptainAmericaTab_StopCondition);
            app.CaptainAmericaTab_DistanceButton.Text = 'Distance';
            app.CaptainAmericaTab_DegreesButton = uiradiobutton(app.CaptainAmericaTab_StopCondition);
            app.CaptainAmericaTab_DegreesButton.Text = 'Degrees';
            app.CaptainAmericaTab_BluetapeButton = uiradiobutton(app.CaptainAmericaTab_StopCondition);
            app.CaptainAmericaTab_BluetapeButton.Text = 'Blue tape';
            app.CaptainAmericaTab_RedtapeButton = uiradiobutton(app.CaptainAmericaTab_StopCondition);
            app.CaptainAmericaTab_RedtapeButton.Text = 'Red tape';
            app.CaptainAmericaTab_ErrorSignal = uibuttongroup(app.CaptainAmericaTab_LowlevelTab);
            app.CaptainAmericaTab_ErrorSignal.Title = 'Error signal';
            app.CaptainAmericaTab_LeftsensorButton = uiradiobutton(app.CaptainAmericaTab_ErrorSignal);
            app.CaptainAmericaTab_LeftsensorButton.Text = 'Left sensor';
            app.CaptainAmericaTab_LeftsensorButton.Value = true;
            app.CaptainAmericaTab_BothsensorsButton = uiradiobutton(app.CaptainAmericaTab_ErrorSignal);
            app.CaptainAmericaTab_BothsensorsButton.Text = 'Both sensors';
            app.CaptainAmericaTab_RightsensorButton = uiradiobutton(app.CaptainAmericaTab_ErrorSignal);
            app.CaptainAmericaTab_RightsensorButton.Text = 'Right sensor';
            app.CaptainAmericaTab_UpdateLowCommandButton = uibutton(app.CaptainAmericaTab_LowlevelTab, 'push');
            app.CaptainAmericaTab_UpdateLowCommandButton.ButtonPushedFcn = createCallbackFcn(app, @CaptainAmericaTab_UpdateLowCommandButtonPushed, true);
            app.CaptainAmericaTab_UpdateLowCommandButton.Text = 'Send low-level command';
            app.StatusMessagesTextAreaLabel = uilabel(app.UIFigure);
            app.StatusMessagesTextAreaLabel.Text = 'Status Messages';
            app.StatusMessagesTextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_v2_Tabbed_layout_20190404

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end